classdef app_GLMCVRidx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        GLMCVRidxLabel                matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        GeneralparametersPanel        matlab.ui.container.Panel
        FrequencybandpassEditFieldLabel  matlab.ui.control.Label
        fpass_minEditField            matlab.ui.control.NumericEditField
        fpass_maxEditField            matlab.ui.control.NumericEditField
        minLabel                      matlab.ui.control.Label
        maxLabel                      matlab.ui.control.Label
        FilterorderEditFieldLabel     matlab.ui.control.Label
        FilterorderEditField          matlab.ui.control.NumericEditField
        ShowadvancedparametersButton  matlab.ui.control.Button
        StatuspendingLabel            matlab.ui.control.Label
        ClosewindowButton             matlab.ui.control.Button
        OverwritePanel                matlab.ui.container.Panel
        YesButton                     matlab.ui.control.Button
        NoButton                      matlab.ui.control.Button
        OverwriteLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        cvrdata
        callingapp % Description
    end
    
    methods (Access = private)
        
        function overwrite_results(app)
            global opts 
            
            app.cvrdata.complete_resdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr), '\CVRidx');
            
            %check if there are previous results
            try
                cd(app.cvrdata.complete_resdir)
                
                list = dir('*.nii');
                prev_res = string({list.name});
            
                if isempty(prev_res) == 0
                    app.OverwritePanel.Visible = 'on';
                end
                
            catch
                app.StatuspendingLabel.Visible = 'on';
                drawnow; 
                opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

                [maps, BP_ref, bpData] = glmCVRidx(app.cvrdata.prepro_sourceData, app.cvrdata.mask.WB, ...
                    app.cvrdata.mask.GM, app.cvrdata.nuisance, opts);
                
                CVRcalculation(app.callingapp, app.cvrdata.res_nr);
                
                delete(app)
                
            end
            
        end
        
        function default_filtorder(app)
            %determine default filter order
            Lowf = app.fpass_minEditField.Value; 
            Highf = app.fpass_maxEditField.Value;
            Fs = 1/opts.TR;
            [ref_voxels ref_coordinates] = grabTimeseries(app.cvrdata.prepro_sourceData,app.cvrdata.mask.GM);
            mean_ts = detrend(mean(ref_voxels,1)); 
            
            SSres = []; BP = [];
            for forder = 1:4
                [b,a] = butter(forder,2*[Lowf, Highf]/Fs);
                BP(forder,:) = filtfilt(b,a,(mean_ts));
                SSres(forder,:)= sum((mean_ts - BP(forder,:).^2),2);
            end
            
            %select closest filter
            [~,I] = min(SSres);
            [b,a] = butter(I,2*[Lowf, Highf]/Fs);
            BP_ref = filtfilt(b,a,(mean_ts));
            app.FilterorderEditField.Value = I;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, prepro_data, mWBmask, mGMmask, nuisance, resdir, results_nr)
            global opts 
            
            %book keeping
            app.callingapp = mainapp;
            app.cvrdata.mask.GM = mGMmask;
            app.cvrdata.mask.WB = mWBmask;
            app.cvrdata.prepro_sourceData = prepro_data;
            app.cvrdata.nuisance = nuisance; 
            app.cvrdata.resdir = resdir;
            app.cvrdata.res_nr = results_nr;
            
            %determine default filter order
            Lowf = app.fpass_minEditField.Value; 
            Highf = app.fpass_maxEditField.Value;
            Fs = 1/opts.TR;
            [ref_voxels ref_coordinates] = grabTimeseries(app.cvrdata.prepro_sourceData,app.cvrdata.mask.GM);
            mean_ts = detrend(mean(ref_voxels,1)); 
            
            SSres = []; BP = [];
            for forder = 1:4
                [b,a] = butter(forder,2*[Lowf, Highf]/Fs);
                BP(forder,:) = filtfilt(b,a,(mean_ts));
                SSres(forder,:)= sum((mean_ts - BP(forder,:).^2),2);
            end
            
            %select closest filter
            [~,I] = min(SSres);
            [b,a] = butter(I,2*[Lowf, Highf]/Fs);
            BP_ref = filtfilt(b,a,(mean_ts));
            app.FilterorderEditField.Value = I;
%             default_filtorder(app)


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            global opts
            
%             try
                app.CalculateButton.Enable = 'off';
                app.StatuspendingLabel.Visible = 'on';
                
                drawnow;
                
                opts.fpass = [app.fpass_minEditField.Value app.fpass_maxEditField.Value];
                opts.filter_order = app.FilterorderEditField.Value;
                
%                 overwrite_results(app)
%                 
%                 delete(app)

                opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

                [maps, BP_ref, bpData] = glmCVRidx(app.cvrdata.prepro_sourceData, app.cvrdata.mask.WB, ...
                    app.cvrdata.mask.GM, app.cvrdata.nuisance, opts);
                
                CVRcalculation(app.callingapp, app.cvrdata.res_nr);
                
                delete(app)
%                 
%             catch 
%                 app.CalculateButton.Enable = 'on';
%                 app.StatuspendingLabel.Text = 'Status: An error has occurred.';
%                 
%             end
        end

        % Button pushed function: ClosewindowButton
        function ClosewindowButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            global opts 
            
            app.OverwritePanel.Visible = 'off';
            app.StatuspendingLabel.Visible = 'on';
            drawnow; 
            opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

            [maps, BP_ref, bpData] = glmCVRidx(app.cvrdata.prepro_sourceData, app.cvrdata.mask.WB, ...
                    app.cvrdata.mask.GM, app.cvrdata.nuisance, opts);
                
            CVRcalculation(app.callingapp, app.cvrdata.res_nr);
            
            delete(app)
            
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            global opts
            
            app.OverwritePanel.Visible = 'off';
            drawnow; 
            
            app.cvrdata.res_nr = app.cvrdata.res_nr + 1;
            opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

            app.StatuspendingLabel.Visible = 'on';
            drawnow; 
            
            [maps, BP_ref, bpData] = glmCVRidx(app.cvrdata.prepro_sourceData, app.cvrdata.mask.WB, ...
                    app.cvrdata.mask.GM, app.cvrdata.nuisance, opts);
            
            CVRcalculation(app.callingapp, app.cvrdata.res_nr);
            
            delete(app)

        end

        % Value changed function: fpass_minEditField
        function fpass_minEditFieldValueChanged(app, event)
            default_filtorder(app)
        end

        % Value changed function: fpass_maxEditField
        function fpass_maxEditFieldValueChanged(app, event)
            default_filtorder(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'seeVR.png';
            app.GLMCVRidxLabel = uilabel(app.UIFigure);
            app.GLMCVRidxLabel.Text = 'GLMCVRidx';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GeneralparametersPanel = uipanel(app.UIFigure);
            app.GeneralparametersPanel.Title = 'General parameters';
            app.FrequencybandpassEditFieldLabel = uilabel(app.GeneralparametersPanel);
            app.FrequencybandpassEditFieldLabel.Text = 'Frequency bandpass';
            app.fpass_minEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.fpass_minEditField.ValueChangedFcn = createCallbackFcn(app, @fpass_minEditFieldValueChanged, true);
            app.fpass_minEditField.Value = 1e-06;
            app.fpass_maxEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.fpass_maxEditField.ValueChangedFcn = createCallbackFcn(app, @fpass_maxEditFieldValueChanged, true);
            app.fpass_maxEditField.Value = 0.1164;
            app.minLabel = uilabel(app.GeneralparametersPanel);
            app.minLabel.Text = 'min.';
            app.maxLabel = uilabel(app.GeneralparametersPanel);
            app.maxLabel.Text = 'max.';
            app.FilterorderEditFieldLabel = uilabel(app.GeneralparametersPanel);
            app.FilterorderEditFieldLabel.Text = 'Filter order';
            app.FilterorderEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.ShowadvancedparametersButton = uibutton(app.UIFigure, 'push');
            app.ShowadvancedparametersButton.Text = 'Show advanced parameters';
            app.StatuspendingLabel = uilabel(app.UIFigure);
            app.StatuspendingLabel.Visible = 'off';
            app.StatuspendingLabel.Text = 'Status: pending...';
            app.ClosewindowButton = uibutton(app.UIFigure, 'push');
            app.ClosewindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClosewindowButtonPushed, true);
            app.ClosewindowButton.Text = 'Close window';
            app.OverwritePanel = uipanel(app.UIFigure);
            app.OverwritePanel.Visible = 'off';
            app.YesButton = uibutton(app.OverwritePanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.OverwritePanel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.OverwriteLabel = uilabel(app.OverwritePanel);
            app.OverwriteLabel.Text = {'Do you want to overwrite '; 'previous function results?'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_GLMCVRidx(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end