classdef app_lagCVR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LagCVRcalculationUIFigure       matlab.ui.Figure
        Image                           matlab.ui.control.Image
        LagCVRLabel                     matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        GeneralparametersPanel          matlab.ui.container.Panel
        ProbeLabel                      matlab.ui.control.Label
        InterpolationfactorEditFieldLabel  matlab.ui.control.Label
        InterpolationfactorEditField    matlab.ui.control.NumericEditField
        RefineregressorDropDownLabel    matlab.ui.control.Label
        RefineregressorDropDown         matlab.ui.control.DropDown
        TracecorrelationEditFieldLabel  matlab.ui.control.Label
        TracecorrelationEditField       matlab.ui.control.NumericEditField
        LowerlagcorrEditFieldLabel      matlab.ui.control.Label
        LowerlagcorrEditField           matlab.ui.control.NumericEditField
        HighlagcorrEditFieldLabel       matlab.ui.control.Label
        HighlagcorrEditField            matlab.ui.control.NumericEditField
        MapsLabel                       matlab.ui.control.Label
        CorrelationmodelDropDownLabel   matlab.ui.control.Label
        CorrelationmodelDropDown        matlab.ui.control.DropDown
        GLMmodelDropDownLabel           matlab.ui.control.Label
        GLMmodelDropDown                matlab.ui.control.DropDown
        CVRmapsDropDownLabel            matlab.ui.control.Label
        CVRmapsDropDown                 matlab.ui.control.DropDown
        AdvancedparametersPanel         matlab.ui.container.Panel
        LoadprobeDropDownLabel          matlab.ui.control.Label
        LoadprobeDropDown               matlab.ui.control.DropDown
        UseGPUDropDownLabel             matlab.ui.control.Label
        UseGPUDropDown                  matlab.ui.control.DropDown
        UseeffectiveprobeDropDownLabel  matlab.ui.control.Label
        UseeffectiveprobeDropDown       matlab.ui.control.DropDown
        RobustlagandCVRDropDownLabel    matlab.ui.control.Label
        RobustlagandCVRDropDown         matlab.ui.control.DropDown
        ShowadvancedparametersButton    matlab.ui.control.Button
        ThresholdsPanel                 matlab.ui.container.Panel
        LowerlagthresholdEditFieldLabel  matlab.ui.control.Label
        LowerlagthresholdEditField      matlab.ui.control.NumericEditField
        UpperlagthresholdEditFieldLabel  matlab.ui.control.Label
        UpperlagthresholdEditField      matlab.ui.control.NumericEditField
        CorrelationthresholdEditFieldLabel  matlab.ui.control.Label
        CorrelationthresholdEditField   matlab.ui.control.NumericEditField
        CO2dataLabel                    matlab.ui.control.Label
        StatuspendingLabel              matlab.ui.control.Label
        ClosewindowButton               matlab.ui.control.Button
        OverwritePanel                  matlab.ui.container.Panel
        YesButton                       matlab.ui.control.Button
        NoButton                        matlab.ui.control.Button
        OverwriteLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        cvrdata % Description
        callingapp % main app
    end
    
    
    methods (Access = private)
        
        function overwrite_results(app)
            global opts 
            
            app.cvrdata.complete_resdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr), '\corrLAG');
            
            %check if there are previous results
            try
                cd(app.cvrdata.complete_resdir)
                
                list = dir('*.nii');
                prev_res = string({list.name});
            
                if isempty(prev_res) == 0
                    app.OverwritePanel.Visible = 'on';
                end
                
            catch
                app.StatuspendingLabel.Visible = 'on';
                drawnow; 
                opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

                [optimized_probe, maps] = lagCVR(app.cvrdata.mask.GM, app.cvrdata.mask.WB, ...
                app.cvrdata.prepro_sourceData, app.cvrdata.probe, app.cvrdata.nuisance, opts);
                
                CVRcalculation(app.callingapp, app.cvrdata.res_nr);
                
                delete(app)
                
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mGMmask, mWBmask, prepro_data, probe, nuisance, resdir, CO2_statement, results_nr)

            %book keeping
            app.callingapp = mainapp;
            app.cvrdata.mask.GM = mGMmask;
            app.cvrdata.mask.WB = mWBmask;
            app.cvrdata.prepro_sourceData = prepro_data;
            app.cvrdata.probe = probe;
            app.cvrdata.nuisance = nuisance;
            app.cvrdata.resdir = resdir;
            app.cvrdata.res_nr = results_nr;
            
            app.CO2dataLabel.Text = CO2_statement;

        end

        % Button pushed function: ShowadvancedparametersButton
        function ShowadvancedparametersButtonPushed(app, event)
            if strcmp(app.ShowadvancedparametersButton.Text, 'Show advanced parameters')
                app.AdvancedparametersPanel.Visible = 'on';
                app.ShowadvancedparametersButton.Text = 'Hide advanced parameters';
            else
                app.AdvancedparametersPanel.Visible = 'off';
                app.ShowadvancedparametersButton.Text = 'Show advanced parameters';
            end
        end

        % Value changed function: RefineregressorDropDown
        function RefineregressorDropDownValueChanged(app, event)
            value = app.RefineregressorDropDown.Value;
            switch value
                case 'Yes'
                    app.ThresholdsPanel.Visible = 'on';
                case 'No'
                    app.ThresholdsPanel.Visible = 'off';
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            global opts
            
            app.CalculateButton.Enable = 'off';
            app.StatuspendingLabel.Visible = 'on'
            drawnow;
            
            try
                opts.interp_factor = app.InterpolationfactorEditField.Value;
                
                if strcmp(app.RefineregressorDropDown.Value, 'Yes')
                    opts.refine_regressor = 1;
                    opts.lowerlagthresh = app.LowerlagthresholdEditField.Value;
                    opts.upperlagthresh = app.UpperlagthresholdEditField.Value;
                    opts.corr_thresh = app.CorrelationthresholdEditField.Value;
                else
                    opts.refine_regressor = 0;
                end
                
                opts.trace_corr = app.TracecorrelationEditField.Value;
                opts.lowlag = app.LowerlagcorrEditField.Value;
                opts.highlag = app.HighlagcorrEditField.Value;
                
                if strcmp(app.CorrelationmodelDropDown.Value, 'Yes')
                    opts.corr_model = 1;
                else
                    opts.corr_model = 0;
                end
                
                if strcmp(app.GLMmodelDropDown.Value, 'Yes')
                    opts.glm_model = 1;
                else
                    opts.glm_model = 0;
                end
                
                if strcmp(app.CVRmapsDropDown.Value, 'Yes')
                    opts.cvr_maps = 1;
                else
                    opts.cvr_maps = 0;
                end  
                
                if strcmp(app.LoadprobeDropDown.Value, 'Yes')
                    opts.load_probe = 1;
                else
                    opts.load_probe = 0;
                end  
                
                if strcmp(app.UseGPUDropDown.Value, 'Yes')
                    opts.gpu = 1;
                else
                    opts.gpu = 0;
                end  
                
                if strcmp(app.UseeffectiveprobeDropDown.Value, 'Yes')
                    opts.eff_probe = 1;
                else
                    opts.eff_probe = 0;
                end  
                
                if strcmp(app.RobustlagandCVRDropDown.Value, 'Yes')
                    opts.robust = 1;
                else
                    opts.robust = 0;
                end  
                
                overwrite_results(app)

            catch
                app.CalculateButton.Enable = 'on';
                app.StatuspendingLabel.Text = 'Status: An error has occurred.'
                drawnow;
                
            end


        end

        % Button pushed function: ClosewindowButton
        function ClosewindowButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            global opts 
            
            app.OverwritePanel.Visible = 'off';
            app.StatuspendingLabel.Visible = 'on';
            drawnow; 
            opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

            [optimized_probe, maps] = lagCVR(app.cvrdata.mask.GM, app.cvrdata.mask.WB, ...
            app.cvrdata.prepro_sourceData, app.cvrdata.probe, app.cvrdata.nuisance, opts);
            
            CVRcalculation(app.callingapp, app.cvrdata.res_nr);
            
            delete(app)
            
            
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            global opts
            
            app.OverwritePanel.Visible = 'off';
            drawnow; 
            
            app.cvrdata.res_nr = app.cvrdata.res_nr + 1;
            opts.resultsdir = append(app.cvrdata.resdir, int2str(app.cvrdata.res_nr));

            app.StatuspendingLabel.Visible = 'on';
            drawnow; 
            
            [optimized_probe, maps] = lagCVR(app.cvrdata.mask.GM, app.cvrdata.mask.WB, ...
            app.cvrdata.prepro_sourceData, app.cvrdata.probe, app.cvrdata.nuisance, opts);
            
            CVRcalculation(app.callingapp, app.cvrdata.res_nr);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LagCVRcalculationUIFigure = uifigure('Visible', 'off');
            app.LagCVRcalculationUIFigure.Name = 'LagCVR calculation';
            app.Image = uiimage(app.LagCVRcalculationUIFigure);
            app.Image.ImageSource = 'seeVR.png';
            app.LagCVRLabel = uilabel(app.LagCVRcalculationUIFigure);
            app.LagCVRLabel.Text = 'LagCVR';
            app.CalculateButton = uibutton(app.LagCVRcalculationUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GeneralparametersPanel = uipanel(app.LagCVRcalculationUIFigure);
            app.GeneralparametersPanel.Title = 'General parameters';
            app.ProbeLabel = uilabel(app.GeneralparametersPanel);
            app.ProbeLabel.Text = 'Probe';
            app.InterpolationfactorEditFieldLabel = uilabel(app.GeneralparametersPanel);
            app.InterpolationfactorEditFieldLabel.Text = 'Interpolation factor';
            app.InterpolationfactorEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.InterpolationfactorEditField.Value = 4;
            app.RefineregressorDropDownLabel = uilabel(app.GeneralparametersPanel);
            app.RefineregressorDropDownLabel.Text = 'Refine regressor';
            app.RefineregressorDropDown = uidropdown(app.GeneralparametersPanel);
            app.RefineregressorDropDown.Items = {'Yes', 'No'};
            app.RefineregressorDropDown.ValueChangedFcn = createCallbackFcn(app, @RefineregressorDropDownValueChanged, true);
            app.RefineregressorDropDown.Value = 'Yes';
            app.TracecorrelationEditFieldLabel = uilabel(app.GeneralparametersPanel);
            app.TracecorrelationEditFieldLabel.Text = 'Trace correlation';
            app.TracecorrelationEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.TracecorrelationEditField.Value = 1;
            app.LowerlagcorrEditFieldLabel = uilabel(app.GeneralparametersPanel);
            app.LowerlagcorrEditFieldLabel.Text = 'Lower lag corr';
            app.LowerlagcorrEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.LowerlagcorrEditField.Limits = [-3 0];
            app.LowerlagcorrEditField.Value = -3;
            app.HighlagcorrEditFieldLabel = uilabel(app.GeneralparametersPanel);
            app.HighlagcorrEditFieldLabel.Text = 'High lag corr';
            app.HighlagcorrEditField = uieditfield(app.GeneralparametersPanel, 'numeric');
            app.HighlagcorrEditField.Limits = [20 90];
            app.HighlagcorrEditField.Value = 60;
            app.MapsLabel = uilabel(app.GeneralparametersPanel);
            app.MapsLabel.Text = 'Maps';
            app.CorrelationmodelDropDownLabel = uilabel(app.GeneralparametersPanel);
            app.CorrelationmodelDropDownLabel.Text = 'Correlation model';
            app.CorrelationmodelDropDown = uidropdown(app.GeneralparametersPanel);
            app.CorrelationmodelDropDown.Items = {'Yes', 'No'};
            app.CorrelationmodelDropDown.Value = 'Yes';
            app.GLMmodelDropDownLabel = uilabel(app.GeneralparametersPanel);
            app.GLMmodelDropDownLabel.Text = 'GLM model';
            app.GLMmodelDropDown = uidropdown(app.GeneralparametersPanel);
            app.GLMmodelDropDown.Items = {'Yes', 'No'};
            app.GLMmodelDropDown.Value = 'No';
            app.CVRmapsDropDownLabel = uilabel(app.GeneralparametersPanel);
            app.CVRmapsDropDownLabel.Text = 'CVR maps';
            app.CVRmapsDropDown = uidropdown(app.GeneralparametersPanel);
            app.CVRmapsDropDown.Items = {'Yes', 'No'};
            app.CVRmapsDropDown.Value = 'Yes';
            app.AdvancedparametersPanel = uipanel(app.LagCVRcalculationUIFigure);
            app.AdvancedparametersPanel.Title = 'Advanced parameters';
            app.AdvancedparametersPanel.Visible = 'off';
            app.LoadprobeDropDownLabel = uilabel(app.AdvancedparametersPanel);
            app.LoadprobeDropDownLabel.Text = 'Load probe';
            app.LoadprobeDropDown = uidropdown(app.AdvancedparametersPanel);
            app.LoadprobeDropDown.Items = {'Yes', 'No'};
            app.LoadprobeDropDown.Value = 'No';
            app.UseGPUDropDownLabel = uilabel(app.AdvancedparametersPanel);
            app.UseGPUDropDownLabel.Text = 'Use GPU';
            app.UseGPUDropDown = uidropdown(app.AdvancedparametersPanel);
            app.UseGPUDropDown.Items = {'Yes', 'No'};
            app.UseGPUDropDown.Value = 'No';
            app.UseeffectiveprobeDropDownLabel = uilabel(app.AdvancedparametersPanel);
            app.UseeffectiveprobeDropDownLabel.Text = 'Use effective probe';
            app.UseeffectiveprobeDropDown = uidropdown(app.AdvancedparametersPanel);
            app.UseeffectiveprobeDropDown.Items = {'Yes', 'No'};
            app.UseeffectiveprobeDropDown.Value = 'No';
            app.RobustlagandCVRDropDownLabel = uilabel(app.AdvancedparametersPanel);
            app.RobustlagandCVRDropDownLabel.Text = 'Robust lag and CVR';
            app.RobustlagandCVRDropDown = uidropdown(app.AdvancedparametersPanel);
            app.RobustlagandCVRDropDown.Items = {'Yes', 'No'};
            app.RobustlagandCVRDropDown.Value = 'No';
            app.ShowadvancedparametersButton = uibutton(app.LagCVRcalculationUIFigure, 'push');
            app.ShowadvancedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ShowadvancedparametersButtonPushed, true);
            app.ShowadvancedparametersButton.Text = 'Show advanced parameters';
            app.ThresholdsPanel = uipanel(app.LagCVRcalculationUIFigure);
            app.ThresholdsPanel.Title = 'Thresholds';
            app.LowerlagthresholdEditFieldLabel = uilabel(app.ThresholdsPanel);
            app.LowerlagthresholdEditFieldLabel.Text = 'Lower lag threshold';
            app.LowerlagthresholdEditField = uieditfield(app.ThresholdsPanel, 'numeric');
            app.LowerlagthresholdEditField.Limits = [-2 0];
            app.LowerlagthresholdEditField.Value = -2;
            app.UpperlagthresholdEditFieldLabel = uilabel(app.ThresholdsPanel);
            app.UpperlagthresholdEditFieldLabel.Text = 'Upper lag threshold';
            app.UpperlagthresholdEditField = uieditfield(app.ThresholdsPanel, 'numeric');
            app.UpperlagthresholdEditField.Limits = [0 2];
            app.UpperlagthresholdEditField.Value = 2;
            app.CorrelationthresholdEditFieldLabel = uilabel(app.ThresholdsPanel);
            app.CorrelationthresholdEditFieldLabel.Text = 'Correlation threshold';
            app.CorrelationthresholdEditField = uieditfield(app.ThresholdsPanel, 'numeric');
            app.CorrelationthresholdEditField.Value = 0.7;
            app.CO2dataLabel = uilabel(app.LagCVRcalculationUIFigure);
            app.CO2dataLabel.Text = 'Without CO2-data';
            app.StatuspendingLabel = uilabel(app.LagCVRcalculationUIFigure);
            app.StatuspendingLabel.Visible = 'off';
            app.StatuspendingLabel.Text = 'Status: pending...';
            app.ClosewindowButton = uibutton(app.LagCVRcalculationUIFigure, 'push');
            app.ClosewindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClosewindowButtonPushed, true);
            app.ClosewindowButton.Text = 'Close window';
            app.OverwritePanel = uipanel(app.LagCVRcalculationUIFigure);
            app.OverwritePanel.Visible = 'off';
            app.YesButton = uibutton(app.OverwritePanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.OverwritePanel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.OverwriteLabel = uilabel(app.OverwritePanel);
            app.OverwriteLabel.Text = {'Do you want to overwrite '; 'previous function results?'};
            % Show the figure after all components are created
            app.LagCVRcalculationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_lagCVR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LagCVRcalculationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LagCVRcalculationUIFigure)
        end
    end
end