classdef func2anat_lin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes_func               matlab.ui.control.UIAxes
        UIAxes_anat               matlab.ui.control.UIAxes
        UIAxes_eval               matlab.ui.control.UIAxes
        UIAxes_eval_2             matlab.ui.control.UIAxes
        Image                     matlab.ui.control.Image
        sliceLabel                matlab.ui.control.Label
        slice_anat                matlab.ui.control.Slider
        slice_func                matlab.ui.control.Slider
        Label_3                   matlab.ui.control.Label
        slice_eval                matlab.ui.control.Slider
        alphaLabel                matlab.ui.control.Label
        slice_alpha               matlab.ui.control.Slider
        runBETfuncButton          matlab.ui.control.Button
        f_funcEditFieldLabel      matlab.ui.control.Label
        f_funcEditField           matlab.ui.control.EditField
        runBETanatButton          matlab.ui.control.Button
        f_anatEditFieldLabel      matlab.ui.control.Label
        f_anatEditField           matlab.ui.control.EditField
        registerdefaultButton     matlab.ui.control.Button
        registercustomButton      matlab.ui.control.Button
        closetoolButton           matlab.ui.control.Button
        OrientationDropDownLabel  matlab.ui.control.Label
        OrientationDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)
        datareg % data
        dpreg   % data properties
    end

    methods (Access = private)

        function [] = plotBET(app,orient)

            func_slice = round(get(app.slice_func,'Value'));
            anat_slice = round(get(app.slice_anat,'Value'));

            switch orient
                case 'tr'

                    if app.dpreg.plotbetfunc
                        ratio = size(app.datareg.func_bet,1)/size(app.datareg.func_bet,3);
                        app.UIAxes_eval.PlotBoxAspectRatio = [1, ratio,  1];
                        app.UIAxes_func.PlotBoxAspectRatio = [1, app.dpreg.xdim/app.dpreg.ydim,  1];
                        imagesc(app.UIAxes_func,app.datareg.func_bet(:,:,func_slice));
                        app.UIAxes_func.Colormap = bone(128);
                    end
                    if app.dpreg.plotbetanat
                        ratio = size(app.datareg.anat_bet,1)/size(app.datareg.anat_bet,3);
                        app.UIAxes_eval.PlotBoxAspectRatio = [1, ratio,  1];
                        app.UIAxes_anat.PlotBoxAspectRatio = [1, app.dpreg.xdim_anat/app.dpreg.ydim_anat,  1];
                        imagesc(app.UIAxes_anat,squeeze(app.datareg.anat_bet(anat_slice,:,:)));
                        app.UIAxes_anat.Colormap = bone(128);
                    end

                case 'sag'

                case 'cor'

            end
        end

        function [] = plotREG(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.datareg.func_to_anat,2)/size(app.datareg.func_to_anat,3);
                    app.UIAxes_eval.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_eval_2.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_eval.Limits = [1 size(app.datareg.func_to_anat,1)];
                    if app.dpreg.hasrunORI1 == 0
                        if app.slice_eval.Value > size(app.datareg.func_to_anat,1)
                            app.slice_eval.Value = roundsize(app.datareg.func_to_anat,1)/2;
                            app.dpreg.hasrunORI1 = 1;
                            app.dpreg.hasrunORI2 = 0;
                            app.dpreg.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.datareg.func_to_anat,1)/size(app.datareg.func_to_anat,3);
                    app.UIAxes_eval.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_eval_2.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_eval.Limits = [1 size(app.datareg.func_to_anat,1)];
                    if app.dpreg.hasrunORI2 == 0
                        if app.slice_eval.Value > size(app.datareg.func_to_anat,2)
                            app.slice_eval.Value = roundsize(app.datareg.func_to_anat,2)/2;
                            app.dpreg.hasrunORI2 = 1;
                            app.dpreg.hasrunORI1 = 0;
                            app.dpreg.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.datareg.func_to_anat,1)/size(app.datareg.func_to_anat,2);
                    app.UIAxes_eval.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_eval_2.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    if app.dpreg.hasrunORI3 == 0
                        app.slice_eval.Limits = [1 size(app.datareg.func_to_anat,1)];
                        if app.slice_eval.Value > size(app.datareg.func_to_anat,3)
                            app.slice_eval.Value = roundsize(app.datareg.func_to_anat,3)/2;
                            app.dpreg.hasrunORI3 = 1;
                            app.dpreg.hasrunORI1 = 0;
                            app.dpreg.hasrunORI2 = 0;
                        end
                    end
            end
            eval_slice = round(get(app.slice_eval,'Value'));

            switch orient
                case 'ORI1'
                    cla(app.UIAxes_eval); cla(app.UIAxes_eval_2);
                    tmp = squeeze(app.datareg.anat_bet(eval_slice,:,:));
                    f1=imagesc(app.UIAxes_eval,tmp); clear tmp
                    app.UIAxes_eval.Colormap = bone(128);
                    tmp = squeeze(app.datareg.func_to_anat(eval_slice,:,:));
                    f2=imagesc(app.UIAxes_eval_2,tmp,"AlphaData",app.slice_alpha.Value);
                    app.UIAxes_eval_2.Colormap = bone(128);
                    app.UIAxes_eval.Position = app.UIAxes_eval_2.Position;
                    linkaxes([app.UIAxes_eval app.UIAxes_eval_2]);
                case 'ORI2'
                    cla(app.UIAxes_eval); cla(app.UIAxes_eval_2)
                    tmp = squeeze(app.datareg.anat_bet(:,eval_slice,:));
                    f1=imagesc(app.UIAxes_eval,tmp); clear tmp
                    app.UIAxes_eval.Colormap = bone(128);
                    tmp = squeeze(app.datareg.func_to_anat(:,eval_slice,:));
                    f2=imagesc(app.UIAxes_eval_2,tmp,"AlphaData",app.slice_alpha.Value);
                    app.UIAxes_eval_2.Colormap = bone(128);
                    app.UIAxes_eval.Position = app.UIAxes_eval_2.Position;
                case 'ORI3'
                    cla(app.UIAxes_eval); cla(app.UIAxes_eval_2);
                    tmp = squeeze(app.datareg.anat_bet(:,:,eval_slice));
                    f1=imagesc(app.UIAxes_eval,tmp); clear tmp
                    app.UIAxes_eval.Colormap = bone(128);
                    tmp = squeeze(app.datareg.func_to_anat(:,:,eval_slice));
                    f2=imagesc(app.UIAxes_eval_2,tmp,"AlphaData",app.slice_alpha.Value);
                    app.UIAxes_eval_2.Colormap = bone(128);
                    app.UIAxes_eval.Position = app.UIAxes_eval_2.Position;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func, anat, elastixDir, opts)
            global opts;
            % book keeping
            app.datareg.func = func;
            app.datareg.mean_func = mean(app.datareg.func,4);
            app.dpreg.matrix_size_func = size(app.datareg.mean_func);
            app.dpreg.voxel_size_func = opts.voxelsize;
            app.datareg.anat = anat;
            app.dpreg.matrix_size_anat = size(anat);
            app.dpreg.voxel_size_anat = opts.voxelsize_anat;
            app.dpreg.elastixdir = elastixDir;
            app.dpreg.betfunc = 0; app.dpreg.betanat = 0;
            app.dpreg.registered = 0;
            % setup registration output directory
            app.dpreg.regDir = fullfile(opts.funcpath,'registration');
            mkdir(app.dpreg.regDir);
            opts.regdir = app.dpreg.regDir;
            opts.elastixDir = app.dpreg.elastixdir;
            app.dpreg.hasrunORI1 = 0; app.dpreg.hasrunORI2 = 0; app.dpreg.hasrunORI3 = 0;
            % plot initial images
            % func
            app.datareg.func_bet = app.datareg.func;
            [app.dpreg.xdim, app.dpreg.ydim, app.dpreg.zdim] = size(app.datareg.func);
            app.slice_func.Limits = [1 size(app.datareg.func,3)];
            app.slice_func.Value = round(size(app.datareg.func,3)/2);
            app.dpreg.plotbetfunc = 1; app.dpreg.plotbetanat = 0;
            plotBET(app, 'tr');

            % anat
            app.datareg.anat_bet = app.datareg.anat;
            [app.dpreg.xdim_anat, app.dpreg.ydim_anat, app.dpreg.zdim_anat] = size(app.datareg.anat);
            app.slice_anat.Limits = [1 size(app.datareg.anat,1)];
            app.slice_anat.Value = round(size(app.datareg.anat,1)/2);
            app.dpreg.plotbetfunc = 0; app.dpreg.plotbetanat = 1;
            plotBET(app, 'tr');
            app.dpreg.isregistered = 0;

        end

        % Button pushed function: runBETfuncButton
        function runBETfuncButtonPushed(app, event)
            global opts;
            f = str2double(app.f_funcEditField.Value);
            app.datareg.func_mask = BET(app.datareg.mean_func,app.dpreg.matrix_size_func,opts.voxelsize,f);
            %check mask datatype
            if isa(app.datareg.func_mask,opts.info.mask.Datatype) 
            else
            app.datareg.func_mask = cast(app.datareg.func_mask,opts.info.mask.Datatype);
            end
                
            app.datareg.func_bet = app.datareg.func.*logical(app.datareg.func_mask);
            % plot result
            app.dpreg.betfunc = 1;
            app.dpreg.plotbetfunc = 1; app.dpreg.plotbetanat = 0;
            plotBET(app, 'tr');

            %save mask in regdir
            if opts.niiwrite
                cd(app.dpreg.regDir);
                disp('saving func (moving) mask')
                disp('saving func (moving) image')
                niftiwrite(cast(imrotate(app.datareg.func_mask,270),opts.info.mask.Datatype),'moving_mask',opts.info.mask);
                niftiwrite(cast(imrotate(app.datareg.mean_func,270),opts.info.map.Datatype),'moving_image',opts.info.map);
            else
                error('ERROR: please load anatomical and functional images using the loadTimeseries (for func) and loadAnat (for anat) functions')
            end

            if app.registerdefaultButton.Visible == 'off'
                if app.dpreg.betfunc && app.dpreg.betanat
                    app.registerdefaultButton.Visible = 'on';
                    app.registercustomButton.Visible = 'on';
                end
            end

        end

        % Button pushed function: runBETanatButton
        function runBETanatButtonPushed(app, event)
            global opts;
            f = str2double(app.f_anatEditField.Value);
            app.datareg.anat_mask = BET(app.datareg.anat,app.dpreg.matrix_size_anat,opts.voxelsize_anat,f);
            %check mask datatype
            if isa(app.datareg.anat_mask,opts.info.mask.Datatype) 
            else
            app.datareg.anat_mask = cast(app.datareg.anat_mask,opts.info.mask.Datatype);
            end
            app.datareg.anat_bet = double(app.datareg.anat).*logical(app.datareg.anat_mask);
            % plot result
            app.dpreg.betanat = 1;
            app.dpreg.plotbetfunc = 0; app.dpreg.plotbetanat = 1;
            plotBET(app, 'tr');

            %save mask in regdir
            if opts.niiwrite
                cd(app.dpreg.regDir);
                disp('saving anat (reference) mask')
                disp('saving anat (reference) image')
                niftiwrite(cast(imrotate(app.datareg.anat_mask,270),opts.info.anat.Datatype),'reference_mask',opts.info.anat);
                niftiwrite(cast(imrotate(app.datareg.anat_bet,270),opts.info.anat.Datatype),'reference_image',opts.info.anat);
            else
                error('ERROR: please load anatomical and functional images using the loadTimeseries (for func) and loadAnat (for anat) functions')
            end

            if app.registerdefaultButton.Visible == 'off'
                if app.dpreg.betfunc && app.dpreg.betanat
                    app.registerdefaultButton.Visible = 'on';
                    app.registercustomButton.Visible = 'on';
                end
            end

        end

        % Value changed function: slice_func
        function slice_funcValueChanged(app, event)
            value = app.slice_func.Value;
            app.dpreg.plotbetfunc = 1; app.dpreg.plotbetanat = 0;
            disp(['slice number: ',int2str(round(value))])
            plotBET(app, 'tr');
        end

        % Value changed function: slice_anat
        function slice_anatValueChanged(app, event)
            value = app.slice_anat.Value;
            app.dpreg.plotbetfunc = 0; app.dpreg.plotbetanat = 1;
            disp(['slice number: ',int2str(round(value))])
            plotBET(app, 'tr');
        end

        % Value changed function: slice_eval
        function slice_evalValueChanged(app, event)
            value = app.slice_eval.Value;
            disp(['slice number: ',int2str(round(value))])
            plotREG(app);
        end

        % Button pushed function: registerdefaultButton
        function registerdefaultButtonPushed(app, event)
            global opts;
            % default elastix parameter file
            opts.param_af = fullfile(app.dpreg.elastixdir,'parameter_files','ParameterFileAf.txt');
            moveImg = fullfile(app.dpreg.regDir,'moving_image');
            moveMask = fullfile(app.dpreg.regDir,'moving_mask');
            refImg = fullfile(app.dpreg.regDir,'reference_image');
            refMask = fullfile(app.dpreg.regDir,'reference_mask');
            [image] = affineReg(moveImg, moveMask, refImg, refMask, opts.param_af, opts.regdir, opts.elastixDir);
            image = imrotate(image,90);
            image = image.*app.datareg.anat_mask;
            image(image == 0) = NaN;
            app.datareg.func_to_anat = image;
            app.OrientationDropDown.Visible = 'on';
            app.slice_alpha.Visible = 'on';
            app.slice_alpha.Value = 0.5;
            app.slice_eval.Visible = 'on';
            app.slice_eval.Value =  size(app.datareg.func_to_anat,1)/2;
            % plot results
            plotREG(app);
            app.dpreg.isregistered = 1;
            app.dpreg.transpath = fullfile(app.dpreg.regDir,'TransformParameters.0.txt');
        end

        % Value changed function: slice_alpha
        function slice_alphaValueChanged(app, event)
            plotREG(app);
        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            value = app.OrientationDropDown.Value;
            disp(['plotting: ',value]);
            plotREG(app);
        end

        % Button pushed function: registercustomButton
        function registercustomButtonPushed(app, event)
            global opts;
            % default elastix parameter file
            cd(opts.elastixDir)
            [file,path] = uigetfile( '*.*')
            opts.param_af = fullfile(path,file);
            moveImg = fullfile(app.dpreg.regDir,'moving_image');
            moveMask = fullfile(app.dpreg.regDir,'moving_mask');
            refImg = fullfile(app.dpreg.regDir,'reference_image');
            refMask = fullfile(app.dpreg.regDir,'reference_mask');
            [image] = affineReg(moveImg, moveMask, refImg, refMask, opts.param_af, opts.regdir, opts.elastixDir);
            image = imrotate(image,90);
            image = image.*app.datareg.anat_mask;
            image(image == 0) = NaN;
            app.datareg.func_to_anat = image;
            app.OrientationDropDown.Visible = 'on';
            app.slice_alpha.Visible = 'on';
            app.slice_alpha.Value = 0.5;
            app.slice_eval.Visible = 'on';
            app.slice_eval.Value =  size(app.datareg.func_to_anat,1)/2;
            % plot results
            plotREG(app);
            app.dpreg.isregistered = 1;
            app.dpreg.transpath = fullfile(app.dpreg.regDir,'TransformParameters.0.txt');
        end

        % Button pushed function: closetoolButton
        function closetoolButtonPushed(app, event)
            if app.dpreg.isregistered
            assignin('base','func_to_anat',app.datareg.func_to_anat);            
            assignin('base','trans_af',app.dpreg.transpath);            
            end
            delete(app)          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_func = uiaxes(app.UIFigure);
            title(app.UIAxes_func, 'extracted func')
            xlabel(app.UIAxes_func, '')
            ylabel(app.UIAxes_func, '')
            zlabel(app.UIAxes_func, '')
            app.UIAxes_func.XTick = [];
            app.UIAxes_func.YTick = [];
            app.UIAxes_anat = uiaxes(app.UIFigure);
            title(app.UIAxes_anat, 'extracted anat')
            xlabel(app.UIAxes_anat, '')
            ylabel(app.UIAxes_anat, '')
            zlabel(app.UIAxes_anat, '')
            app.UIAxes_anat.XTick = [];
            app.UIAxes_anat.YTick = [];
            app.UIAxes_eval = uiaxes(app.UIFigure);
            title(app.UIAxes_eval, 'check registration')
            xlabel(app.UIAxes_eval, '')
            ylabel(app.UIAxes_eval, '')
            zlabel(app.UIAxes_eval, '')
            app.UIAxes_eval.XTick = [];
            app.UIAxes_eval.YTick = [];
            app.UIAxes_eval.Visible = 'off';
            app.UIAxes_eval_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_eval_2, 'check registration')
            xlabel(app.UIAxes_eval_2, '')
            ylabel(app.UIAxes_eval_2, '')
            zlabel(app.UIAxes_eval_2, '')
            app.UIAxes_eval_2.XTick = [];
            app.UIAxes_eval_2.YTick = [];
            app.UIAxes_eval_2.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tooltip = {'www.seeVR.nl'};
            app.Image.ImageSource = 'seeVR.png';
            app.sliceLabel = uilabel(app.UIFigure);
            app.sliceLabel.Visible = 'off';
            app.sliceLabel.Text = 'slice';
            app.slice_anat = uislider(app.UIFigure);
            app.slice_anat.Limits = [1 240];
            app.slice_anat.MajorTicks = [];
            app.slice_anat.ValueChangedFcn = createCallbackFcn(app, @slice_anatValueChanged, true);
            app.slice_anat.MinorTicks = [];
            app.slice_anat.Value = 10;
            app.slice_func = uislider(app.UIFigure);
            app.slice_func.Limits = [1 40];
            app.slice_func.MajorTicks = [];
            app.slice_func.ValueChangedFcn = createCallbackFcn(app, @slice_funcValueChanged, true);
            app.slice_func.MinorTicks = [];
            app.slice_func.Value = 10;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.slice_eval = uislider(app.UIFigure);
            app.slice_eval.Limits = [1 240];
            app.slice_eval.MajorTicks = [];
            app.slice_eval.ValueChangedFcn = createCallbackFcn(app, @slice_evalValueChanged, true);
            app.slice_eval.MinorTicks = [];
            app.slice_eval.Visible = 'off';
            app.slice_eval.Value = 30;
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Visible = 'off';
            app.alphaLabel.Text = 'alpha';
            app.slice_alpha = uislider(app.UIFigure);
            app.slice_alpha.Limits = [0 1];
            app.slice_alpha.MajorTicks = [];
            app.slice_alpha.ValueChangedFcn = createCallbackFcn(app, @slice_alphaValueChanged, true);
            app.slice_alpha.MinorTicks = [];
            app.slice_alpha.Visible = 'off';
            app.slice_alpha.Value = 0.5;
            app.runBETfuncButton = uibutton(app.UIFigure, 'push');
            app.runBETfuncButton.ButtonPushedFcn = createCallbackFcn(app, @runBETfuncButtonPushed, true);
            app.runBETfuncButton.Text = 'run BET func';
            app.f_funcEditFieldLabel = uilabel(app.UIFigure);
            app.f_funcEditFieldLabel.Text = 'f_func';
            app.f_funcEditField = uieditfield(app.UIFigure, 'text');
            app.f_funcEditField.Tooltip = {'smaller values give larger brain outline'};
            app.f_funcEditField.Value = '0.5';
            app.runBETanatButton = uibutton(app.UIFigure, 'push');
            app.runBETanatButton.ButtonPushedFcn = createCallbackFcn(app, @runBETanatButtonPushed, true);
            app.runBETanatButton.Text = 'run BET anat';
            app.f_anatEditFieldLabel = uilabel(app.UIFigure);
            app.f_anatEditFieldLabel.Text = 'f_anat';
            app.f_anatEditField = uieditfield(app.UIFigure, 'text');
            app.f_anatEditField.Tooltip = {'smaller values give larger brain outline'};
            app.f_anatEditField.Value = '0.5';
            app.registerdefaultButton = uibutton(app.UIFigure, 'push');
            app.registerdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @registerdefaultButtonPushed, true);
            app.registerdefaultButton.Visible = 'off';
            app.registerdefaultButton.Text = 'register default';
            app.registercustomButton = uibutton(app.UIFigure, 'push');
            app.registercustomButton.ButtonPushedFcn = createCallbackFcn(app, @registercustomButtonPushed, true);
            app.registercustomButton.Visible = 'off';
            app.registercustomButton.Text = 'register custom';
            app.closetoolButton = uibutton(app.UIFigure, 'push');
            app.closetoolButton.ButtonPushedFcn = createCallbackFcn(app, @closetoolButtonPushed, true);
            app.closetoolButton.Text = 'close tool';
            app.OrientationDropDownLabel = uilabel(app.UIFigure);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.UIFigure);
            app.OrientationDropDown.Items = {'ORI1', 'ORI2', 'ORI3'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Visible = 'off';
            app.OrientationDropDown.Tooltip = {'select image orientation'};
            app.OrientationDropDown.Value = 'ORI1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = func2anat_lin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end