classdef plotresults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotCVRandhemodynamiclagresultsUIFigure  matlab.ui.Figure
        Image                      matlab.ui.control.Image
        PlotresultsLabel           matlab.ui.control.Label
        ParametermapDropDownLabel  matlab.ui.control.Label
        ParametermapDropDown       matlab.ui.control.DropDown
        slice_Slider               matlab.ui.control.Slider
        UIAxes_map                 matlab.ui.control.UIAxes
        ParametermapLabel          matlab.ui.control.Label
        SliceLabel                 matlab.ui.control.Label
        ColormapLabel              matlab.ui.control.Label
        ColormapDropDown           matlab.ui.control.DropDown
        ScalingEditFieldLabel      matlab.ui.control.Label
        Scaling_min_EditField      matlab.ui.control.NumericEditField
        Scaling_max_EditField      matlab.ui.control.NumericEditField
        OrientationDropDownLabel   matlab.ui.control.Label
        OrientationDropDown        matlab.ui.control.DropDown
        ClosewindowButton          matlab.ui.control.Button
        ErrorLabel                 matlab.ui.control.Label
        minLabel                   matlab.ui.control.Label
        maxLabel                   matlab.ui.control.Label
        SettodefaultButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        images      % maps
        callingapp  %main app
        dp          % data properties
    end
    
    methods (Access = private)
        function set_slider(app)
            %This function sets the slider limits, depending on the chosen
            %orientation
            
            [app.dp.xdim, app.dp.ydim, app.dp.zdim, app.dp.vols] = size(app.images.map);
            
            orient = app.OrientationDropDown.Value;
            
            switch orient
                case 'ORI1'            
                    set(app.slice_Slider,'Limits',[1 app.dp.zdim]);
                    set(app.slice_Slider,'Value',ceil(app.dp.zdim/2));
                    app.SliceLabel.Text = append('Slice: ', int2str(app.slice_Slider.Value))
                    
                case 'ORI2'            
                    set(app.slice_Slider,'Limits',[1 app.dp.ydim]);
                    set(app.slice_Slider,'Value',ceil(app.dp.ydim/2));
                    app.SliceLabel.Text = append('Slice: ', int2str(app.slice_Slider.Value))
                    
                case 'ORI3'            
                    set(app.slice_Slider,'Limits',[1 app.dp.xdim]);
                    set(app.slice_Slider,'Value',ceil(app.dp.xdim/2));
                    app.SliceLabel.Text = append('Slice: ', int2str(app.slice_Slider.Value))
                    
            end
        end
        

        function set_colormap_dropdown(app)
            %Find all the colormap filenames and put them in the dropdown
            
            app.images.colormap_dir = append(app.images.seeVR_dir, '\plotting\colormaps');
            
            cd(app.images.colormap_dir)
            
            list = dir('*.m');
            app.images.colormaps = string({list.name});
            app.images.colormapsnames = erase(app.images.colormaps, '.m');
            
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.Items = [app.ColormapDropDown.Items app.images.colormapsnames(:)];
            
            app.ColormapDropDown.Value = 'viridis';
            
        end
        
        function load_newmap(app)
            cd(app.images.dir)
            if isfile(app.ParametermapDropDown.Value)
                [app.images.map] = loadImage(app.images.dir, app.ParametermapDropDown.Value);
                
            else
                for foldername = app.images.subFolderNames
                    cd(append(app.images.dir, '\', string(foldername)))
                    if isfile(app.ParametermapDropDown.Value)
                        [app.images.map] = loadImage(append(app.images.dir, '\', string(foldername)), app.ParametermapDropDown.Value);
                    end
                end
            end
        end        
        

        function set_defaultscaling(app)
            data = app.images.map;
            data(data == 0) = [];
            med = median(data);
            clear data 
            
            if med < 0
                med = -med;
            end
            
            app.images.scalingmin = 0;
            app.images.scalingmax = med;
            app.Scaling_min_EditField.Value = double(app.images.scalingmin);
            app.Scaling_max_EditField.Value = double(app.images.scalingmax);
            
            
        end
        
        
        function plot_colormap(app)
            orient = app.OrientationDropDown.Value;
            
            app.images.mapcopy = app.images.map;
            app.images.mapcopy(app.images.mapcopy == 0) = NaN;

            switch orient
                case 'ORI1'
                    ratio = app.dp.xdim / app.dp.ydim;
                    app.UIAxes_map.PlotBoxAspectRatio = [1, ratio,  1];
                    
                    slice = round(app.slice_Slider.Value);           
                    
                    cla(app.UIAxes_map);
                    colormap = app.ColormapDropDown.Value;
                    func = str2func(colormap);

                    imagesc(app.UIAxes_map,squeeze(app.images.mapcopy(:,:,slice,1)), [app.images.scalingmin app.images.scalingmax]);
                    app.UIAxes_map.Colormap = func(128);
                    
            
                case 'ORI2'
                    ratio = app.dp.zdim / app.dp.xdim;
                    app.UIAxes_map.PlotBoxAspectRatio = [1, ratio,  1];
                    
                    slice = round(app.slice_Slider.Value);           
                    
                    cla(app.UIAxes_map);
                    colormap = app.ColormapDropDown.Value ;
                    func = str2func(colormap);
        
                    imagesc(app.UIAxes_map,imrotate(squeeze(app.images.mapcopy(:,slice,:,1)), 90), [app.images.scalingmin app.images.scalingmax]);
                    app.UIAxes_map.Colormap = func(128);


                case 'ORI3'
                    ratio = app.dp.zdim / app.dp.ydim;
                    app.UIAxes_map.PlotBoxAspectRatio = [1, ratio,  1];
                    
                    slice = round(app.slice_Slider.Value);           
                    
                    cla(app.UIAxes_map);
                    colormap = app.ColormapDropDown.Value ;
                    func = str2func(colormap);
        
                    imagesc(app.UIAxes_map,imrotate(squeeze(app.images.mapcopy(slice,:,:,1)), 90), [app.images.scalingmin app.images.scalingmax]);
                    app.UIAxes_map.Colormap = func(128);

            end
            
            clear app.images.mapcopy
        end

        
        function image_transformation(app)
%             global opts;
%             
%             app.StatusregLabel.Visible = 'on';
%             drawnow;
%             
%             % default elastix parameter file
%             opts.param_tf = fullfile(app.images.datapath,'BOLD','registration', 'TransformParameters.0.txt');
%             opts.transformdir = fullfile(app.images.datapath, 'BOLD', 'transformation');
%             
%             cd(app.images.dir)
%             if isfile(app.ParametermapDropDown.Value)
%                 imagedir = app.images.dir;
%             else
%                 for foldername = app.images.subFolderNames
%                     cd(append(app.images.dir, foldername))
%                     if isfile(app.ParametermapDropDown.Value)
%                         imagedir = append(app.images.dir, foldername);
%                     end
%                 end
%             end
%         
%             inputImg = fullfile(imagedir, app.ParametermapDropDown.Value);
% 
%             [image] = transformixReg(inputImg, opts.param_tf, opts.transformdir, app.images.elastix_dir);
%             image = imrotate(image,90);
%             image(image == 0) = NaN;
%             app.images.transformed_img = image;
% 
%             % plot results
%             plotREG(app);
%             app.dpreg.isregistered = 1;
%             app.dpreg.transpath = fullfile(app.dpreg.regDir,'TransformParameters.0.txt');
%             
%             setup_reg(app)
%             
%             app.StatusregLabel.Visible = 'off';
%             
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dir_results, dir_seeVR, dirname, elastix_dir, datapath_dir)

            app.callingapp = mainapp;
            app.images.dir = append(dir_results, dirname);
            app.images.seeVR_dir = dir_seeVR;
            app.images.elastix_dir = elastix_dir;
            app.images.datapath = datapath_dir;
            
            %set scaling parameters
            app.images.scalingmin = app.Scaling_min_EditField.Value;
            app.images.scalingmax = app.Scaling_max_EditField.Value;
            
            %check for results folder and get all filenames
            if isfolder(app.images.dir)
                cd(app.images.dir)

                list = dir('*.nii');
                app.images.filenames = string({list.name});

                %check for directories in main folder
                files = dir(app.images.dir);
                
                dirFlags = [files.isdir];  % Get a logical vector that tells which is a directory.
                subFolders = files(dirFlags); 
                % Get only the folder names into a cell array.
                app.images.subFolderNames = {subFolders(3:end).name}   % Start at 3 to skip . and ..
            
                for foldername = app.images.subFolderNames
                    folder_dir = append(app.images.dir, '\', string(foldername));
                    cd(folder_dir)
                    
                    list = dir('*nii');
                    app.images.filenames = [app.images.filenames string({list.name})];
                end

                %add all filenames to dropdown
                app.ParametermapDropDown.Items = {};
                app.ParametermapDropDown.Items = [app.ParametermapDropDown.Items app.images.filenames(:)];
                
                cd(app.images.dir)
                if isfile(app.ParametermapDropDown.Value)
                    [app.images.map] = loadImage(app.images.dir, app.ParametermapDropDown.Value);
                    
                else
                    for foldername = app.images.subFolderNames
                        cd(append(app.images.dir, foldername))
                        if isfile(app.ParametermapDropDown.Value)
                            [app.images.map] = loadImage(append(app.images.dir, foldername), app.ParametermapDropDown.Value);
                        end
                    end
                end
                
                set_colormap_dropdown(app)
                set_slider(app)
                
%                 if isfolder(fullfile(app.images.datapath, 'BOLD', 'registration'))
%                     image_transformation(app);
%                     set_defaultscaling(app);
%                     plot_colormap(app);                    
%                     
%                 else
%                     set_defaultscaling(app);
%                     plot_colormap(app);
                
                set_defaultscaling(app);
                plot_colormap(app);
                app.ParametermapLabel.Text = {'Parameter map:'; app.ParametermapDropDown.Value}

            %if there are no CVR results, delete app    
            else
                delete(app)
                
            end


        end

        % Value changed function: ParametermapDropDown
        function ParametermapDropDownValueChanged(app, event)
            value = app.ParametermapDropDown.Value;
            
            cla(app.UIAxes_map);
            load_newmap(app);  
            set_defaultscaling(app);            
            plot_colormap(app);
            
            app.ParametermapLabel.Text = {'Parameter map:'; app.ParametermapDropDown.Value}
            
        end

        % Value changed function: slice_Slider
        function slice_SliderValueChanged(app, event)
            value = app.slice_Slider.Value;
            
            cla(app.UIAxes_map);
            plot_colormap(app);
            app.SliceLabel.Text = append('Slice: ', int2str(round(value)));
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
    
            plot_colormap(app);
            
        end

        % Value changed function: Scaling_min_EditField
        function Scaling_min_EditFieldValueChanged(app, event)
            value = app.Scaling_min_EditField.Value;
            
            if value > app.images.scalingmax
                app.ErrorLabel.Text = 'Error: Minimum must be lower than maximum.';
                app.ErrorLabel.Visible = 'on';
            elseif value == app.images.scalingmax
                app.ErrorLabel.Text = 'Error: Minimum and maximum can not be equal.';
                app.ErrorLabel.Visible = 'on';
            else
                app.ErrorLabel.Visible = 'off';
                app.images.scalingmin = value;
                plot_colormap(app)
                
            end
        end

        % Value changed function: Scaling_max_EditField
        function Scaling_max_EditFieldValueChanged(app, event)
            value = app.Scaling_max_EditField.Value;
            
            if value < app.images.scalingmin
                app.ErrorLabel.Text = 'Error: Maximum must be higher than minimum.';
                app.ErrorLabel.Visible = 'on';
            elseif value == app.images.scalingmin
                app.ErrorLabel.Text = 'Error: Minimum and maximum can not be equal.';
                app.ErrorLabel.Visible = 'on';
            else
                app.ErrorLabel.Visible = 'off';
                app.images.scalingmax = value;
                plot_colormap(app)
                
            end

        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            value = app.OrientationDropDown.Value;
            
            set_slider(app)
            
            plot_colormap(app);
        end

        % Button pushed function: ClosewindowButton
        function ClosewindowButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SettodefaultButton
        function SettodefaultButtonPushed(app, event)
            set_defaultscaling(app)
            plot_colormap(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotCVRandhemodynamiclagresultsUIFigure = uifigure('Visible', 'off');
            app.PlotCVRandhemodynamiclagresultsUIFigure.Name = 'Plot CVR and hemodynamic lag results';
            app.Image = uiimage(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.Image.ImageSource = 'seeVR.png';
            app.PlotresultsLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.PlotresultsLabel.Text = 'Plot results';
            app.ParametermapDropDownLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ParametermapDropDownLabel.Text = 'Parameter map';
            app.ParametermapDropDown = uidropdown(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ParametermapDropDown.Items = {'maps'};
            app.ParametermapDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametermapDropDownValueChanged, true);
            app.ParametermapDropDown.Value = 'maps';
            app.slice_Slider = uislider(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.slice_Slider.MajorTicks = [];
            app.slice_Slider.ValueChangedFcn = createCallbackFcn(app, @slice_SliderValueChanged, true);
            app.slice_Slider.MinorTicks = [];
            app.UIAxes_map = uiaxes(app.PlotCVRandhemodynamiclagresultsUIFigure);
            title(app.UIAxes_map, '')
            xlabel(app.UIAxes_map, '')
            ylabel(app.UIAxes_map, '')
            app.UIAxes_map.XTick = [];
            app.UIAxes_map.YTick = [];
            app.ParametermapLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ParametermapLabel.Text = 'Parameter map: ';
            app.SliceLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.SliceLabel.Text = 'Slice: ';
            app.ColormapLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ColormapLabel.Text = 'Color map';
            app.ColormapDropDown = uidropdown(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ScalingEditFieldLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ScalingEditFieldLabel.Text = 'Scaling';
            app.Scaling_min_EditField = uieditfield(app.PlotCVRandhemodynamiclagresultsUIFigure, 'numeric');
            app.Scaling_min_EditField.ValueChangedFcn = createCallbackFcn(app, @Scaling_min_EditFieldValueChanged, true);
            app.Scaling_max_EditField = uieditfield(app.PlotCVRandhemodynamiclagresultsUIFigure, 'numeric');
            app.Scaling_max_EditField.ValueChangedFcn = createCallbackFcn(app, @Scaling_max_EditFieldValueChanged, true);
            app.Scaling_max_EditField.Value = 2;
            app.OrientationDropDownLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.OrientationDropDown.Items = {'ORI1', 'ORI2', 'ORI3'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Tooltip = {'select image orientation'};
            app.OrientationDropDown.Value = 'ORI1';
            app.ClosewindowButton = uibutton(app.PlotCVRandhemodynamiclagresultsUIFigure, 'push');
            app.ClosewindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClosewindowButtonPushed, true);
            app.ClosewindowButton.Text = 'Close window';
            app.ErrorLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error: Minimum must be lower than maximum.';
            app.minLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.minLabel.Text = 'min.';
            app.maxLabel = uilabel(app.PlotCVRandhemodynamiclagresultsUIFigure);
            app.maxLabel.Text = 'max.';
            app.SettodefaultButton = uibutton(app.PlotCVRandhemodynamiclagresultsUIFigure, 'push');
            app.SettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SettodefaultButtonPushed, true);
            app.SettodefaultButton.Text = 'Set to default';
            % Show the figure after all components are created
            app.PlotCVRandhemodynamiclagresultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotresults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotCVRandhemodynamiclagresultsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotCVRandhemodynamiclagresultsUIFigure)
        end
    end
end