classdef seeVR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeeVRUserInterfaceUIFigure      matlab.ui.Figure
        ModifiedmaskLabel               matlab.ui.control.Label
        RemoveallpreprocessingButton    matlab.ui.control.Button
        SmoothingactLabel               matlab.ui.control.Label
        DenoisingactLabel               matlab.ui.control.Label
        RegisterfunctoanatButton        matlab.ui.control.Button
        SelectinputdirectoryButton      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        percentageLabel                 matlab.ui.control.Label
        SlicetSNRLabel                  matlab.ui.control.Label
        VolumesourceLabel               matlab.ui.control.Label
        SlicesourceLabel                matlab.ui.control.Label
        slice_Slider_tSNR               matlab.ui.control.Slider
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        pctileEdit                      matlab.ui.control.EditField
        remLV                           matlab.ui.control.Button
        slice_Slider                    matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        volume_Slider                   matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        UIAxes_tSNR                     matlab.ui.control.UIAxes
        UIAxes_mask                     matlab.ui.control.UIAxes
        UIAxes_source                   matlab.ui.control.UIAxes
        DenoisingTab                    matlab.ui.container.Tab
        Panel_denoisetwice              matlab.ui.container.Panel
        DenoiseHoldButton               matlab.ui.control.Button
        DenoiseContinueButton           matlab.ui.control.Button
        DenoisePanelLabel               matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        LicensestatusLabel              matlab.ui.control.Label
        WhichmaskDropDown               matlab.ui.control.DropDown
        WhichmaskDropDownLabel          matlab.ui.control.Label
        Panel_denoising                 matlab.ui.container.Panel
        FamilynumberDropDown            matlab.ui.control.DropDown
        FamilynumberDropDownLabel       matlab.ui.control.Label
        WavfamDropDown                  matlab.ui.control.DropDown
        WaveletfamilyDropDownLabel      matlab.ui.control.Label
        WavlevelEditField               matlab.ui.control.NumericEditField
        WaveletlevelEditFieldLabel      matlab.ui.control.Label
        MethodwavDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ThresruleDropDown               matlab.ui.control.DropDown
        ThresholdruleDropDownLabel      matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LeveldepButton                  matlab.ui.control.RadioButton
        LevelindepButton                matlab.ui.control.RadioButton
        NoiseestLabel                   matlab.ui.control.Label
        WindowsizeEditField             matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel        matlab.ui.control.Label
        Smoothingfactor01EditField      matlab.ui.control.NumericEditField
        Smoothingfactor01EditFieldLabel  matlab.ui.control.Label
        MethodmovDropDown               matlab.ui.control.DropDown
        MethodDropDown_2Label           matlab.ui.control.Label
        FilterorderEditField            matlab.ui.control.NumericEditField
        FilterorderEditFieldLabel       matlab.ui.control.Label
        FrequencybpmaxEditField         matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        FrequencybpminEditField         matlab.ui.control.NumericEditField
        FrequencybandpassEditFieldLabel  matlab.ui.control.Label
        DenoisingmethodDropDown         matlab.ui.control.DropDown
        DenoisingmethodDropDownLabel    matlab.ui.control.Label
        ApplydenButton                  matlab.ui.control.Button
        DenoisingLabel                  matlab.ui.control.Label
        UIAxes_noiseremoval             matlab.ui.control.UIAxes
        SmoothingTab                    matlab.ui.container.Tab
        Panel_smoothtwice               matlab.ui.container.Panel
        SmoothHoldButton                matlab.ui.control.Button
        SmoothContinueButton            matlab.ui.control.Button
        SmoothPanelLabel                matlab.ui.control.Label
        smoothsliceLabel                matlab.ui.control.Label
        SliceSlider_smoothing           matlab.ui.control.Slider
        SliceSliderLabel_2              matlab.ui.control.Label
        StatussmoothingLabel            matlab.ui.control.Label
        Panel_smoothing                 matlab.ui.container.Panel
        WhichsmoothmaskDropDown         matlab.ui.control.DropDown
        WhichmaskDropDown_2Label        matlab.ui.control.Label
        SpatialdimensionsDropDown       matlab.ui.control.DropDown
        SpatialdimensionsDropDownLabel  matlab.ui.control.Label
        FWHM_x                          matlab.ui.control.NumericEditField
        FWHMofkernelmmEditFieldLabel    matlab.ui.control.Label
        zLabel                          matlab.ui.control.Label
        FWHM_z                          matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        FWHM_y                          matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        SigmarangeEditField             matlab.ui.control.NumericEditField
        SigmarangeEditFieldLabel        matlab.ui.control.Label
        FilterwidthmmEditField          matlab.ui.control.NumericEditField
        FilterwidthmmEditFieldLabel     matlab.ui.control.Label
        SmoothingmethodDropDown         matlab.ui.control.DropDown
        SmoothingmethodDropDownLabel    matlab.ui.control.Label
        ApplysmoothingButton            matlab.ui.control.Button
        SmoothingLabel                  matlab.ui.control.Label
        UIAxes_smoothing                matlab.ui.control.UIAxes
        DataProcessingTab               matlab.ui.container.Tab
        chopDataDropDown                matlab.ui.control.DropDown
        WouldyouliketoprocessonlyaportionofthetimeseriesLabel  matlab.ui.control.Label
        StatusCVRLabel                  matlab.ui.control.Label
        ProbeDropDown                   matlab.ui.control.DropDown
        ProbeDropDownLabel              matlab.ui.control.Label
        StatusRespirActLabel            matlab.ui.control.Label
        LoadtxtfileButton               matlab.ui.control.Button
        LoadRespirActdataButton         matlab.ui.control.Button
        DataProcessingLabel             matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        RSBHCVRButton                   matlab.ui.control.Button
        Reclag_noco2_Button             matlab.ui.control.Button
        lagCVR_noco2_Button             matlab.ui.control.Button
        WithouttracesLabel              matlab.ui.control.Label
        Reclag_co2_Button               matlab.ui.control.Button
        lagCVR_co2_Button               matlab.ui.control.Button
        TauDispersionButton             matlab.ui.control.Button
        WithtracesLabel                 matlab.ui.control.Label
        PlottingTab                     matlab.ui.container.Tab
        WithouttracesLabel_2            matlab.ui.control.Label
        WithtracesLabel_2               matlab.ui.control.Label
        PlotRSBHCVRButton               matlab.ui.control.Button
        PlotReclag_no_Button            matlab.ui.control.Button
        PlotlagCVR_no_Button            matlab.ui.control.Button
        PlotReclag_co2_Button           matlab.ui.control.Button
        PlotlagCVR_co2_Button           matlab.ui.control.Button
        PlotTauDispersionButton         matlab.ui.control.Button
        DataPlottingLabel               matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        dirs % data directories
        files % data filenames
        data % data
        dp %general data properties
        chosenmask % Chosen mask for denoising
        CVRapp % window app for CVR calculation
        Plotapp    % Description
    end

    properties (Access = public)
        optimized_probe % Description
        maps
    end

    methods (Access = private)

        function calctSNR(app)
            %calculate temporal SNR
            [voxel_ts, coordinates] = grabTimeseries(app.data.sourceData, app.data.mask.WB);
            SD = nanstd(voxel_ts,0,2);
            MN = nanmean(voxel_ts,2);
            tSNR = zeros([1 numel(app.data.mask.WB)]);
            tSNR(coordinates) = MN./SD;
            app.data.tSNR = reshape(tSNR, size(app.data.mask.WB));
        end

        function setuptab1(app)
            % set visibility after loading
            app.UIAxes_source.Visible = 'on';
            app.volume_Slider.Visible = 'on';
            app.slice_Slider.Visible = 'on';
            app.slice_Slider_tSNR.Visible = 'on';
            app.remLV.Visible = 'on';
            app.pctileEdit.Visible = 'on';
            app.OrientationDropDown.Visible = 'on';
            app.OrientationDropDownLabel.Visible = 'on';
            app.SlicesourceLabel.Visible = 'on';
            app.SlicetSNRLabel.Visible = 'on';
            app.VolumesourceLabel.Visible = 'on';
            app.RemoveallpreprocessingButton.Visible = 'on';
            app.DenoisingactLabel.Visible = 'on';
            app.SmoothingactLabel.Visible = 'on';
            app.ModifiedmaskLabel.Visible = 'on';
            app.percentageLabel.Visible = 'on';

            if app.dp.isT1; app.RegisterfunctoanatButton.Visible = 'on'; end

            %set limits of volume and slice sliders
            set(app.volume_Slider,'Limits',[1 app.dp.vols]);
            set(app.volume_Slider,'Value',1);
            app.VolumesourceLabel.Text = append('Volume: ', int2str(app.volume_Slider.Value));
            set(app.slice_Slider,'Limits',[1 app.dp.zdim]);
            set(app.slice_Slider,'Value',ceil(app.dp.zdim/2));
            app.SlicesourceLabel.Text = append('Slice: ', int2str(app.slice_Slider.Value));
            set(app.slice_Slider_tSNR,'Limits',[1 app.dp.zdim]);
            set(app.slice_Slider_tSNR,'Value',ceil(app.dp.zdim/2));
            app.SlicetSNRLabel.Text = append('Slice: ', int2str(app.slice_Slider_tSNR.Value));
        end

        function [] = plotSourceData(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.data.tSNR,1)/size(app.data.tSNR,2);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_Slider.Limits = [1 size(app.data.tSNR,3)];
                    if app.dp.hasrunORI1 == 0
                        if app.slice_Slider.Value > size(app.data.tSNR,3)
                            app.slice_Slider.Value = roundsize(app.data.tSNR,3)/2;
                            app.dp.hasrunORI1 = 1;
                            app.dp.hasrunORI2 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,1);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_Slider.Limits = [1 size(app.data.tSNR,2)];
                    if app.dp.hasrunORI2 == 0
                        if app.slice_Slider.Value > size(app.data.tSNR,2)
                            app.slice_Slider.Value = roundsize(app.data.tSNRt,2)/2;
                            app.dp.hasrunORI2 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,2);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    if app.dp.hasrunORI3 == 0
                        app.slice_Slider.Limits = [1 size(app.data.tSNR,1)];
                        if app.slice_Slider > size(app.data.tSNR,1)
                            app.slice_Slider.Value = roundsize(app.data.tSNR,1)/2;
                            app.dp.hasrunORI3 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI2 = 0;
                        end
                    end
            end
            slice = round(get(app.slice_Slider,'Value'));
            vol = round(get(app.volume_Slider,'Value'));

            switch orient
                case 'ORI1'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,squeeze(app.data.sourceData(:,:,slice,vol)));
                case 'ORI2'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,imrotate(squeeze(app.data.sourceData(:,slice,:,vol)),90));
                case 'ORI3'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,imrotate(squeeze(app.data.sourceData(slice,:,:,vol)),90));
            end
            app.UIAxes_source.Colormap = bone(128);
        end

        % function to plot SNR map with removed voxel overlay

        function [] = plottSNR(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.data.tSNR,1)/size(app.data.tSNR,2);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    app.slice_Slider_tSNR.Limits = [1 size(app.data.tSNR,3)];
                    if app.dp.hasrunORI1 == 0
                        if app.slice_Slider_tSNR.Value > size(app.data.tSNR,3)
                            app.slice_Slider_tSNR.Value = roundsize(app.data.tSNR,3)/2;
                            app.dp.hasrunORI1 = 1;
                            app.dp.hasrunORI2 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,1);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1  ratio  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    app.slice_Slider_tSNR.Limits = [1 size(app.data.tSNR,2)];
                    if app.dp.hasrunORI2 == 0
                        if app.slice_Slider_tSNR.Value > size(app.data.tSNR,2)
                            app.slice_Slider_tSNR.Value = roundsize(app.data.tSNRt,2)/2;
                            app.dp.hasrunORI2 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,2);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    if app.dp.hasrunORI3 == 0
                        app.slice_Slider_tSNR.Limits = [1 size(app.data.tSNR,1)];
                        if app.slice_Slider_tSNR > size(app.data.tSNR,1)
                            app.slice_Slider_tSNR.Value = roundsize(app.data.tSNR,1)/2;
                            app.dp.hasrunORI3 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI2 = 0;
                        end
                    end
            end
            slice = round(get(app.slice_Slider_tSNR,'Value'));

            if app.dp.LVfilter == 0

                switch orient % no large vessel removal
                    case 'ORI1'
                        cla(app.UIAxes_tSNR);
                        imagesc(app.UIAxes_tSNR,squeeze(app.data.tSNR(:,:,slice)));
                    case 'ORI2'
                        cla(app.UIAxes_tSNR);
                        tmp = imrotate(squeeze(app.data.tSNR(:,slice,:)),90);
                        imagesc(app.UIAxes_tSNR,tmp); clear tmp
                    case 'ORI3'
                        cla(app.UIAxes_tSNR);
                        tmp = imrotate(squeeze(app.data.tSNR(slice,:,:)),90);
                        imagesc(app.UIAxes_tSNR,tmp); clear tmp
                end
                app.UIAxes_tSNR.Colormap = bone(128);

            else
                app.UIAxes_tSNR.CLim = [0 100];
                switch orient % overlay removed voxels
                    case 'ORI1'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = squeeze(app.data.tSNR(:,:,slice));
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = squeeze(app.data.mask.LVmask(:,:,slice));
                        tmp(tmp == 0) = NaN;
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;

                    case 'ORI2'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = imrotate(squeeze(app.data.tSNR(:,slice,:)),90);
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = imrotate(squeeze(app.data.mask.LVmask(:,slice,:)),90);
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;

                    case 'ORI3'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = imrotate(squeeze(app.data.tSNR(slice,:,:)),90);
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = imrotate(squeeze(app.data.mask.LVmask(slice,:,:)),90);
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;

                end
            end
        end


        function makemaskdropdown(app, dropdown)
            %Make a dropdown with the available files
            dropdown.Items = {};

            if isempty(app.files.func_brain) == 0
                dropdown.Items = [dropdown.Items 'Whole brain'];
            end

            if isempty(app.files.func_GM) == 0
                dropdown.Items = [dropdown.Items 'Grey matter'];
            end

            if isempty(app.files.func_WM) == 0
                dropdown.Items = [dropdown.Items 'White matter'];
            end

            if isempty(app.files.func_CSF) == 0
                dropdown.Items = [dropdown.Items 'CSF'];
            end

            if isempty(app.files.func_lesion) == 0
                dropdown.Items = [dropdown.Items 'Lesion'];
            end

            if isempty(app.files.func_edema) == 0
                dropdown.Items = [dropdown.Items 'Edema'];
            end

            if isempty(app.files.func_ref) == 0
                dropdown.Items = [dropdown.Items 'Reference'];
            end


        end

        function readmaskdropdown(app, dropdown)
            %read out dropdown with different masks

            switch dropdown.Value
                case 'Grey matter'
                    app.chosenmask = app.data.mask.GM;
                case 'White matter'
                    app.chosenmask = app.data.mask.WM;
                case 'CSF'
                    app.chosenmask = app.data.mask.CSF;
                case 'Whole brain'
                    app.chosenmask = app.data.mask.WB;
                case 'Lesion'
                    app.chosenmask = app.data.mask.lesion;
                case 'Edema'
                    app.chosenmask = app.data.mask.edema;
                case 'Reference'
                    app.chosenmask = app.data.mask.ref;
            end

        end

        function defaultfilterorder(app)
            %set filter order to default
            global opts

            [ref_voxels ref_coordinates] = grabTimeseries(app.data.sourceData, app.data.mask.GM);

            Fs = 1/opts.TR;
            mean_ts = detrend(mean(ref_voxels,1)); % detrend reference time-series
            Lowf = 0.000001; Highf = 0.1164; %Hz

            SSres = []; BP = [];
            for forder = 1:4
                [b,a] = butter(forder,2*[Lowf, Highf]/Fs);
                BP(forder,:) = filtfilt(double(b),double(a),double(mean_ts));
                SSres(forder,:)= sum((mean_ts - BP(forder,:).^2),2);
            end

            %select closest filter
            [~,I] = min(SSres);
            opts.filter_order = I;

            app.FilterorderEditField.Value = I;

        end


        function defaultsigmarange(app)
            %set sigma range to default

            if ndims(app.data.sourceData) > 3
                app.SigmarangeEditField.Value = double(10*ROIstd(mean(app.data.sourceData(:,:,:,1:10),4),app.data.mask.WB));
            else
                tmp = app.data.sourceData.*app.data.mask.WB;
                tmp = tmp(:);
                tmp(tmp == 0) = [];
                app.SigmarangeEditField.Value = 10*std(tmp);
            end
        end

        function apply_smoothing(app)
            global opts

            if  strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') && strcmp(app.SmoothingactLabel.Text,'Smoothing: no')
                data_eval_str = 'normTimeseries(app.data.prepro_sourceData, app.data.mask.WB, [1 20])';
            else
                data_eval_str = 'normTimeseries(app.data.sourceData, app.data.mask.WB, [1 20])';
            end

            method = app.SmoothingmethodDropDown.Value;
            opts.verbose = 0;
            app.data.smooth = 1;
            app.StatussmoothingLabel.Text = 'Status: Smoothing started, pending...';
            app.StatussmoothingLabel.Visible = 'on';
            drawnow;

            %determine spatial dimensions and FWHM
            if strcmp(app.SpatialdimensionsDropDown.Value, '2D')
                opts.spatialdim = 2;
                opts.FWHM = [app.FWHM_x.Value app.FWHM_y.Value];
            else
                opts.spatialdim = 3;
                opts.FWHM = [app.FWHM_x.Value app.FWHM_y.Value app.FWHM_z.Value];
            end

            %check if modified mask exist
            if app.dp.LVfilter == 1
                switch app.WhichsmoothmaskDropDown.Value
                    case 'Modified mask'
                        mask_eval_str = 'app.data.mask.mmask';
                    case 'Original mask'
                        mask_eval_str = 'app.data.mask.WB';
                end

            else
                mask_eval_str = 'app.data.mask.WB';
            end

            switch method
                case 'Matlab Image Guided'
                    opts.filter = 'imguided';
                    app.data.prepro_sourceData = filterData(eval(data_eval_str), squeeze(app.data.sourceData(:,:,:,1)), eval(mask_eval_str), opts);

                case 'Bilateral'
                    opts.filter = 'bilateral';
                    opts.sigma_range = app.SigmarangeEditField.Value;
                    app.data.prepro_sourceData = filterData(eval(data_eval_str), squeeze(app.data.sourceData(:,:,:,1)), eval(mask_eval_str), opts);

                case 'TIPS'
                    opts.filter = 'tips';
                    opts.sigma_range = app.SigmarangeEditField.Value;
                    app.data.prepro_sourceData = filterData(eval(data_eval_str), squeeze(app.data.sourceData(:,:,:,1)), eval(mask_eval_str), opts);

                case 'Standard Gaussian'
                    opts.FWHM = app.FWHM_x.Value;
                    opts.filtwidth = app.FilterwidthmmEditField.Value;
                    app.data.prepro_sourceData = smthData(eval(data_eval_str), eval(mask_eval_str), opts);

                case 'Gaussian'
                    opts.filter = 'gaussian';
                    app.data.prepro_sourceData = filterData(eval(data_eval_str), squeeze(app.data.sourceData(:,:,:,1)), eval(mask_eval_str), opts);
            end

            app.StatussmoothingLabel.Text = append('Status: ', app.SmoothingmethodDropDown.Value, ...
                ' smoothing on data is completed.');
            app.StatussmoothingLabel.Visible = 'on';
            app.UIAxes_smoothing.Title.String = 'Subtracted smoothing mask from data';

            app.data.prepro_sourceData(app.data.prepro_sourceData == 0) = NaN;

            app.data.smthout = mean(normTimeseries(app.data.sourceData, app.data.mask.WB, [1 20]),4) - mean(app.data.prepro_sourceData,4);

            app.SmoothingactLabel.Text = 'Smoothing: yes';
            app.SmoothingactLabel.FontWeight = 'bold';

            %show image subtraction
            ratio = size(app.data.smthout,1)/size(app.data.smthout,2);
            app.UIAxes_smoothing.PlotBoxAspectRatio = [1, ratio, 1];

            slice = round(app.SliceSlider_smoothing.Value);

            imagesc(app.UIAxes_smoothing, squeeze(app.data.smthout(:,:,slice)));
            app.UIAxes_smoothing.Colormap = bone(128);
            app.UIAxes_smoothing.Visible = 'on';
            app.SliceSlider_smoothing.Visible = 'on';
            app.smoothsliceLabel.Visible = 'on';
            app.SliceSliderLabel_2.Visible = 'on';

            app.ApplysmoothingButton.Enable = 'on';

        end

        function removeallpreprocessing(app)
            app.data.prepro_sourceData = app.data.sourceData;

            %Remove denoising
            cla(app.UIAxes_noiseremoval);
            app.data.denoise = 0;

            TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
            plot(app.UIAxes_noiseremoval, TS1, 'k');
            legend(app.UIAxes_noiseremoval, 'original')

            app.LicensestatusLabel.Visible = 'off';
            app.ErrorLabel.Visible = 'off';
            app.DenoisingactLabel.Text = 'Denoising: no';
            app.DenoisingactLabel.FontWeight = 'normal';
            app.ApplydenButton.Enable = 'on';
            app.Panel_denoisetwice.Visible = 'off';

            %Remove smoothing
            app.data.smooth = 0;
            app.SmoothingactLabel.Text = 'Smoothing: no';
            app.SmoothingactLabel.FontWeight = 'normal';
            app.StatussmoothingLabel.Visible = 'off';
            app.data.smoothData = [];
            app.ApplysmoothingButton.Enable = 'on';
            cla(app.UIAxes_smoothing);
            app.UIAxes_smoothing.Visible = 'off';
            app.SliceSlider_smoothing.Visible = 'off';
            app.smoothsliceLabel.Visible = 'off';
            app.SliceSliderLabel_2.Visible = 'off';
            app.Panel_smoothtwice.Visible = 'off';
            app.WhichmaskDropDown_2Label.Visible = 'off';
            app.WhichsmoothmaskDropDown.Visible = 'off';

            app.StatusCVRLabel.Text = 'Status: No calculation has been done.';


        end

        function apply_denoising(app)
            global opts

            if  strcmp(app.DenoisingactLabel.Text, 'Denoising: no') && strcmp(app.SmoothingactLabel.Text,'Smoothing: yes')
                data_eval_str = 'app.data.prepro_sourceData';
            else
                data_eval_str = 'app.data.sourceData';
            end

            filter = app.DenoisingmethodDropDown.Value;
            app.LicensestatusLabel.Text = 'Status: Denoising started, pending...';
            app.LicensestatusLabel.Visible = 'on';
            drawnow;

            app.DenoisingactLabel.Text = 'Denoising: yes';
            app.DenoisingactLabel.FontWeight = 'bold';
            app.data.denoise = 1;

            if strcmp(filter, 'Wavelet')
                opts.wavelet = 1;

                if license('test', 'wavelet_toolbox')
                    wavfam = append(app.WavfamDropDown.Value, app.FamilynumberDropDown.Value)
                    opts.family = wavfam;
                    opts.level = app.WavlevelEditField.Value;
                    opts.DenMeth = app.MethodwavDropDown.Value;
                    opts.ThreshRule = app.ThresruleDropDown.Value;
                    if app.LevelindepButton.Value == 1
                        opts.NoEst = 'LevelIndependent';
                    else
                        opts.NoEst = 'LevelDependent';
                    end

                    opts.verbose = 0;

                    app.data.prepro_sourceData = wavDenoise(eval(data_eval_str), app.data.mask.WB, opts);
                    app.LicensestatusLabel.Text = 'Status: Wavelet toolbox license accepted, discreet wavelet denoising completed.';
                end


            elseif strcmp(filter, 'Moving window')
                opts.wavelet = 0;
                opts.method = app.MethodmovDropDown.Value;
                opts.FACTOR = app.Smoothingfactor01EditField.Value;
                opts.winsize = app.WindowsizeEditField.Value;

                [xx yy zz dyn] = size(eval(data_eval_str));

                [voxel_ts, coordinates] = grabTimeseries(eval(data_eval_str), app.data.mask.WB);

                voxel_ts = smoothdata(voxel_ts,2,opts.method);
                app.data.prepro_sourceData = zeros([xx*yy*zz dyn]);
                app.data.prepro_sourceData(coordinates,:) = voxel_ts;
                app.data.prepro_sourceData = reshape(app.data.prepro_sourceData, size(eval(data_eval_str)));

                app.LicensestatusLabel.Text = 'Status: Moving window denoising completed.';
                app.LicensestatusLabel.Visible = 'on';

            elseif strcmp(filter, 'Bandpass filter')
                %max freq should be higher than min freq
                if app.FrequencybpminEditField.Value > app.FrequencybpmaxEditField.Value
                    app.ErrorLabel.Text = 'Minimum frequency should be lower than maximum frequency';
                    app.ErrorLabel.Visible = 'on';
                    app.ApplydenButton.Enable = 'on';
                    app.LicensestatusLabel.Visible = 'off';
                    return
                end

                %set minimum freq limit
                if app.FrequencybpminEditField.Value < 0.00001
                    app.FrequencybpminEditField.Value = 0.00001;
                    app.ErrorLabel.Text = 'Minimum frequency should be higher than 1e-6';
                    app.ErrorLabel.Visible = 'on';
                end

                %set maximum freq limit
                maxFreq = 1/(2*opts.TR);
                if app.FrequencybpmaxEditField.Value > maxFreq;
                    app.FrequencybpmaxEditField.Value = maxFreq;
                    app.ErrorLabel.Text = append('Maximum frequency should be lower or equal to ', int2str(maxFreq));
                    app.ErrorLabel.Visible = 'on';
                end


                opts.fpass = [app.FrequencybpminEditField.Value app.FrequencybpmaxEditField.Value];
                opts.filterorder = app.FilterorderEditField.Value;

                %apply bandpass filtering
                app.data.prepro_sourceData = bandpassfilt(eval(data_eval_str), app.data.mask.WB, app.data.mask.GM, [], opts);

                app.LicensestatusLabel.Text = 'Status: Bandpass filter denoising completed.';
                app.LicensestatusLabel.Visible = 'on';


            end


            %plot original and denoised timeseries plot
            readmaskdropdown(app, app.WhichmaskDropDown)

            cla(app.UIAxes_noiseremoval);

            TS1 = meanTimeseries(normTimeseries(app.data.sourceData, app.data.mask.WB, [1 20]), app.chosenmask);
            if app.data.smooth
                TS2 = meanTimeseries(app.data.prepro_sourceData, app.chosenmask);
            else
                TS2 = meanTimeseries(normTimeseries(app.data.prepro_sourceData, app.data.mask.WB, [1 20]), app.chosenmask);
            end

            hold(app.UIAxes_noiseremoval, 'on');
            plot(app.UIAxes_noiseremoval, TS1, 'k');
            plot(app.UIAxes_noiseremoval, TS2, 'c');
            hold(app.UIAxes_noiseremoval, 'off');

            legend(app.UIAxes_noiseremoval, 'original', 'denoised');

            %enable denoise button again
            app.ApplydenButton.Enable = 'on';

            clear prepro_data
        end

        function check_previousresults(app)
            % get the folder contents
            d = dir(app.dirs.datapath);

            % remove all files (isdir property is 0)
            dfolders = d([d(:).isdir]);

            % remove '.' and '..'
            dfolders = dfolders(~ismember({dfolders(:).name},{'.','..'}));
            dfolders = {dfolders.name};
            last_result = 0;

            for i = dfolders
                name = string(i);
                if count(name, 'RESULTS') == 1
                    new_result = erase(name, 'RESULTS');
                    new_result = str2num(new_result);
                    if new_result > last_result
                        last_result = new_result;
                    end
                end
            end

            app.dp.last_results = last_result + 1;
        end
    end

    methods (Access = public)

        function CVRcalculation(app, results_nr)
            app.StatusCVRLabel.Text = append(app.data.currentCVRfunc, ' calculated succesfully.');

            switch app.data.currentCVRfunc
                case 'LagCVR without CO2-data'
                    app.PlotlagCVR_no_Button.Enable = 'on';
                    app.dp.res_nr_lagCVR = results_nr;
                    app.dirs.CVRdatapath_lagCVR_compl = append(app.dirs.CVRdatapath_lagCVR, int2str(results_nr));

                case 'Recursive lag without CO2-data'
                    app.PlotReclag_no_Button.Enable = 'on';
                    app.dp.res_nr_recLag = results_nr;
                    app.dirs.CVRdatapath_recLag_compl = append(app.dirs.CVRdatapath_recLag, int2str(results_nr));

                case 'GLMCVRidx'
                    app.PlotRSBHCVRButton.Enable = 'on';
                    app.dp.res_nr_GLM = results_nr;
                    app.dirs.CVRdatapath_GLM_compl = append(app.dirs.CVRdatapath_GLM, int2str(results_nr));

                case 'LagCVR with CO2-data'
                    app.PlotlagCVR_co2_Button.Enable = 'on';
                    app.dp.res_nr_lagCVR = results_nr;
                    app.dirs.CVRdatapath_lagCVR_compl = append(app.dirs.CVRdatapath_lagCVR, int2str(results_nr));

                case 'Recursive lag with CO2-data'
                    app.PlotReclag_co2_Button.Enable = 'on';
                    app.dp.res_nr_recLag = results_nr;
                    app.dirs.CVRdatapath_recLag_compl = append(app.dirs.CVRdatapath_recLag, int2str(results_nr));

                case 'Dispersion'
                    %                     app.PlotTauDispersionButton.Enable = 'on';
                    %                     app.dp.res_nr_disp = results_nr;


            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run pathdef function
            global opts;
            [dirs, files] = seeVR_set_variables;
            % set folder and filename tags
            app.dirs = dirs;
            app.files = files;
        end

        % Button pushed function: SelectinputdirectoryButton
        function SelectinputdirectoryButtonPushed(app, event)
            %initialize the global opts structure
            global opts;

            %select input directory
            app.dirs.datapath = fullfile(uigetdir);
            [app.data, app.dp] = check_loadData(app.dirs, app.files);
            cd(app.dirs.datapath)
            opts.resultsdir = app.dirs.datapath;

            %%%FIRST TAB
            calctSNR(app)
            setuptab1(app)

            %display source image
            app.dp.hasrunORI1 = 0; app.dp.hasrunORI2 = 0; app.dp.hasrunORI3 = 0;
            app.dp.LVfilter = 0;
            plotSourceData(app)
            plottSNR(app)

            %%%DENOISING TAB
            %set winsize default and determine limit of 10%
            app.WindowsizeEditField.Limits = [0 0.1*size(app.data.sourceData,4)];
            app.WindowsizeEditField.Value = round(0.05*size(app.data.sourceData,4));

            app.data.denoise = 0;

            %set up denoising plot with original timeseries data
            makemaskdropdown(app, app.WhichmaskDropDown)
            readmaskdropdown(app, app.WhichmaskDropDown)

            RemoveallpreprocessingButtonPushed(app)

            defaultfilterorder(app)

            %set frequency bandpass min and max default
            app.FrequencybpminEditField.Limits = [0.00001, 1/(2*opts.TR)];
            app.FrequencybpmaxEditField.Limits = [0.00002, 1/(2*opts.TR)];

            %%%SMOOTHING TAB
            app.data.smooth = 0;

            defaultsigmarange(app)
            set(app.SliceSlider_smoothing,'Limits',[1 app.dp.zdim]);
            set(app.SliceSlider_smoothing,'Value',ceil(app.dp.zdim/2));
            app.smoothsliceLabel.Text = append('Current slice: ', int2str(app.SliceSlider_smoothing.Value));

            %%%CVR TAB
            makemaskdropdown(app, app.ProbeDropDown)
            check_previousresults(app)
            app.dp.res_nr_lagCVR = app.dp.last_results;
            app.dp.res_nr_recLag = app.dp.last_results;
            app.dp.res_nr_GLM = app.dp.last_results;
            %app.dp.res_nr_disp = 1;


        end

        % Value changed function: volume_Slider
        function volume_SliderValueChanged(app, event)
            value = round(app.volume_Slider.Value);
            plotSourceData(app)
            disp(['volume number: ',int2str(value)])
            app.VolumesourceLabel.Text = append('Volume: ', int2str(value));
        end

        % Value changed function: slice_Slider
        function slice_SliderValueChanged(app, event)
            value = round(app.slice_Slider.Value);
            plotSourceData(app)
            disp(['slice number: ',int2str(value)])
            app.SlicesourceLabel.Text = append('Slice: ', int2str(value));
        end

        % Button pushed function: RegisterfunctoanatButton
        function RegisterfunctoanatButtonPushed(app, event)
            % register mean BOLD data to anatomical via dedicated UI
            global opts;
            func2anat_lin(app.data.sourceData,app.data.anat,app.dirs.elastix,opts);

        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            plotSourceData(app)
            plottSNR(app)

            app.VolumesourceLabel.Text = append('Volume: ', int2str(round(app.volume_Slider.Value)));
            app.SlicesourceLabel.Text = append('Slice: ', int2str(round(app.slice_Slider.Value)));
            app.SlicetSNRLabel.Text = append('Slice: ', int2str(round(app.slice_Slider.Value)));
        end

        % Value changed function: slice_Slider_tSNR
        function slice_Slider_tSNRValueChanged(app, event)
            value = round(app.slice_Slider_tSNR.Value);
            plottSNR(app)
            disp(['slice number: ',int2str(value)])
            app.SlicetSNRLabel.Text = append('Slice: ', int2str(value));
        end

        % Button pushed function: remLV
        function remLVButtonPushed(app, event)
            global opts;

            app.remLV.Enable = 'off';
            drawnow;

            opts.LVpercentile = str2double(app.pctileEdit.Value);
            opts.verbose = 0;
            [app.data.mask.mmask] = remLV(imrotate(app.data.sourceData,270),imrotate(app.data.mask.WB,270),opts);
            app.data.mask.mmask = logical(imrotate(app.data.mask.mmask,90));
            app.dp.LVfilter = 1;

            app.WhichsmoothmaskDropDown.Visible = 'on';
            app.WhichmaskDropDown_2Label.Visible = 'on';
            app.ModifiedmaskLabel.Text = 'Modified mask: yes';
            app.ModifiedmaskLabel.FontWeight = 'bold';

            % calculate LVmask
            app.data.mask.LVmask = abs(app.data.mask.WB - app.data.mask.mmask).*app.data.mask.WB;
            plottSNR(app)

            app.remLV.Enable = 'on';
        end

        % Value changed function: DenoisingmethodDropDown
        function DenoisingmethodDropDownValueChanged(app, event)
            value = app.DenoisingmethodDropDown.Value;
            if strcmp(value, 'Wavelet')
                app.ButtonGroup.Visible = 'on';
                app.NoiseestLabel.Visible = 'on';
                app.ThresruleDropDown.Visible = 'on';
                app.ThresholdruleDropDownLabel.Visible = 'on';
                app.WavlevelEditField.Visible = 'on';
                app.WaveletlevelEditFieldLabel.Visible = 'on';
                app.WavfamDropDown.Visible = 'on';
                app.WaveletfamilyDropDownLabel.Visible = 'on';
                app.FamilynumberDropDown.Visible = 'on';
                app.FamilynumberDropDownLabel.Visible = 'on';
                app.MethodwavDropDown.Visible = 'on';
                app.MethodDropDownLabel.Visible = 'on';

                app.MethodmovDropDown.Visible = 'off';
                app.MethodDropDown_2Label.Visible = 'off';
                app.WindowsizeEditField.Visible = 'off';
                app.WindowsizeEditFieldLabel.Visible = 'off';
                app.Smoothingfactor01EditField.Visible = 'off';
                app.Smoothingfactor01EditFieldLabel.Visible = 'off';

                app.FrequencybpminEditField.Visible = 'off';
                app.FrequencybandpassEditFieldLabel.Visible = 'off';
                app.FrequencybpmaxEditField.Visible = 'off';
                app.FilterorderEditField.Visible = 'off';
                app.FilterorderEditFieldLabel.Visible = 'off';
                app.minLabel.Visible = 'off';
                app.maxLabel.Visible = 'off';


            elseif strcmp(value, 'Moving window')
                app.ButtonGroup.Visible = 'off';
                app.NoiseestLabel.Visible = 'off';
                app.ThresruleDropDown.Visible = 'off';
                app.ThresholdruleDropDownLabel.Visible = 'off';
                app.WavlevelEditField.Visible = 'off';
                app.WaveletlevelEditFieldLabel.Visible = 'off';
                app.WavfamDropDown.Visible = 'off';
                app.WaveletfamilyDropDownLabel.Visible = 'off';
                app.FamilynumberDropDown.Visible = 'off';
                app.FamilynumberDropDownLabel.Visible = 'off';
                app.MethodwavDropDown.Visible = 'off';
                app.MethodDropDownLabel.Visible = 'off';

                app.MethodmovDropDown.Visible = 'on';
                app.MethodDropDown_2Label.Visible = 'on';
                app.WindowsizeEditField.Visible = 'on';
                app.WindowsizeEditFieldLabel.Visible = 'on';
                app.Smoothingfactor01EditField.Visible = 'on';
                app.Smoothingfactor01EditFieldLabel.Visible = 'on';

                app.FrequencybpminEditField.Visible = 'off';
                app.FrequencybandpassEditFieldLabel.Visible = 'off';
                app.FrequencybpmaxEditField.Visible = 'off';
                app.FilterorderEditField.Visible = 'off';
                app.FilterorderEditFieldLabel.Visible = 'off';
                app.minLabel.Visible = 'off';
                app.maxLabel.Visible = 'off';

            elseif strcmp(value, 'Bandpass filter')
                app.ButtonGroup.Visible = 'off';
                app.NoiseestLabel.Visible = 'off';
                app.ThresruleDropDown.Visible = 'off';
                app.ThresholdruleDropDownLabel.Visible = 'off';
                app.WavlevelEditField.Visible = 'off';
                app.WaveletlevelEditFieldLabel.Visible = 'off';
                app.WavfamDropDown.Visible = 'off';
                app.WaveletfamilyDropDownLabel.Visible = 'off';
                app.FamilynumberDropDown.Visible = 'off';
                app.FamilynumberDropDownLabel.Visible = 'off';
                app.MethodwavDropDown.Visible = 'off';
                app.MethodDropDownLabel.Visible = 'off';

                app.MethodmovDropDown.Visible = 'off';
                app.MethodDropDown_2Label.Visible = 'off';
                app.WindowsizeEditField.Visible = 'off';
                app.WindowsizeEditFieldLabel.Visible = 'off';
                app.Smoothingfactor01EditField.Visible = 'off';
                app.Smoothingfactor01EditFieldLabel.Visible = 'off';

                app.FrequencybpminEditField.Visible = 'on';
                app.FrequencybandpassEditFieldLabel.Visible = 'on';
                app.FrequencybpmaxEditField.Visible = 'on';
                app.FilterorderEditField.Visible = 'on';
                app.FilterorderEditFieldLabel.Visible = 'on';
                app.minLabel.Visible = 'on';
                app.maxLabel.Visible = 'on';
            end
        end

        % Value changed function: WavfamDropDown
        function WavfamDropDownValueChanged(app, event)
            value = app.WavfamDropDown.Value;
            if strcmp(value, 'db')
                app.FamilynumberDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
                app.FamilynumberDropDown.Value = '4';
            elseif strcmp(value, 'sym')
                app.FamilynumberDropDown.Items = {'2', '3', '4', '5', '6', '7', '8'};
                app.FamilynumberDropDown.Value = '2';
            elseif strcmp(value, 'fk')
                app.FamilynumberDropDown.Items = {'4', '6', '8', '14', '18', '22'};
                app.FamilynumberDropDown.Value = '4';
            elseif strcmp(value, 'bior')
                app.FamilynumberDropDown.Items = {'1.1', '1.3', '1.5', '2.2', '2.4', '2.6','2.8', '3.1', '3.3', '3.5', '3.7', '3.9', '4.4', '5.5', '6.8'};
                app.FamilynumberDropDown.Value = '1.1';
            elseif strcmp(value, 'coif')
                app.FamilynumberDropDown.Items = {'1', '2', '3', '4', '5'};
                app.FamilynumberDropDown.Value = '1';

            end
        end

        % Value changed function: MethodwavDropDown
        function MethodwavDropDownValueChanged(app, event)
            value = app.MethodwavDropDown.Value;
            if strcmp(value, 'UniversalThreshold')
                app.ThresruleDropDown.Items = {'Hard', 'Soft'};
                app.ThresruleDropDown.Value = 'Hard';
            elseif strcmp(value, 'Bayes')
                app.ThresruleDropDown.Items = {'Median', 'Mean', 'Soft', 'Hard'};
                app.ThresruleDropDown.Value = 'Median';
            elseif strcmp(value, 'BlockJS')
                app.ThresruleDropDown.Items = {'James-Stein'};
                app.ThresruleDropDown.Value = 'James-Stein';
            elseif strcmp(value, 'FDR')
                app.ThresruleDropDown.Items = {'Hard'};
                app.ThresruleDropDown.Value = 'Hard';
            elseif strcmp(value, 'Minimax')
                app.ThresruleDropDown.Items = {'Soft', 'Hard'};
                app.ThresruleDropDown.Value = 'Soft';
            elseif strcmp(value, 'SURE')
                app.ThresruleDropDown.Items = {'Soft', 'Hard'};
                app.ThresruleDropDown.Value = 'Soft';

            end
        end

        % Button pushed function: ApplydenButton
        function ApplydenoisingButtonPushed(app, event)
            global opts

            app.ErrorLabel.Visible = 'off';
            app.ApplydenButton.Enable = 'off';

            drawnow;


            try
                if app.data.denoise == 1
                    if app.data.smooth == 1
                        app.DenoisePanelLabel.Text = {'Denoising has already been performed.', ...
                            'If you wish to denoise data again,', 'then existing processes will be removed.', ...
                            'Smoothing can be applied first.'}
                        app.Panel_denoisetwice.Visible = 'on';
                    else
                        app.DenoisePanelLabel.Text = {'Denoising has already been performed.', ...
                            'If you wish to denoise data again,', 'then existing processes will be removed', ...
                            'and source data will be used.'}
                        app.Panel_denoisetwice.Visible = 'on';

                    end

                else
                    apply_denoising(app)

                end


                %give status update when an error has occurred
            catch
                app.LicensestatusLabel.Text = 'Status: Error occurred while denoising';
                app.DenoisingactLabel.Text = 'Denoising: no';
                app.DenoisingactLabel.FontWeight = 'normal';
                app.ApplydenButton.Enable = 'on';
                app.data.denoise = 0;
                app.ErrorLabel.Visible = 'off';

                cla(app.UIAxes_noiseremoval);
                TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
                plot(app.UIAxes_noiseremoval, TS1, 'k');
                legend(app.UIAxes_noiseremoval, 'original')

            end
        end

        % Button pushed function: DenoiseContinueButton
        function DenoiseContinueButtonPushed(app, event)
            app.Panel_denoisetwice.Visible = 'off';
            drawnow;

            %only if no previous smoothing is done, denoising can be done
            %directly
            if app.data.smooth == 0
                removeallpreprocessing(app)
                app.ApplydenButton.Enable = 'off';
                drawnow;
                apply_denoising(app)
            else
                removeallpreprocessing(app)
            end

        end

        % Button pushed function: DenoiseHoldButton
        function DenoiseHoldButtonPushed(app, event)
            app.Panel_denoisetwice.Visible = 'off';
            app.ApplydenButton.Enable = 'on';
        end

        % Value changed function: WhichmaskDropDown
        function WhichmaskDropDownValueChanged(app, event)
            cla(app.UIAxes_noiseremoval);

            readmaskdropdown(app, app.WhichmaskDropDown)

            if app.data.denoise == 0
                TS1 = meanTimeseries(normTimeseries(app.data.sourceData, app.data.mask.WB, [1 20]), app.chosenmask);
                plot(app.UIAxes_noiseremoval, TS1, 'k');
                legend(app.UIAxes_noiseremoval, 'original');

            else
                TS1 = meanTimeseries(normTimeseries(app.data.sourceData, app.data.mask.WB, [1 20]), app.chosenmask);
                if app.data.smooth
                    TS2 = meanTimeseries(app.data.prepro_sourceData, app.chosenmask);
                else
                    TS2 = meanTimeseries(normTimeseries(app.data.prepro_sourceData, app.data.mask.WB, [1 20]), app.chosenmask);
                end

                hold(app.UIAxes_noiseremoval, 'on');
                plot(app.UIAxes_noiseremoval, TS1, 'k');
                plot(app.UIAxes_noiseremoval, TS2, 'c');
                hold(app.UIAxes_noiseremoval, 'off');
                legend(app.UIAxes_noiseremoval, 'original', 'denoised');

            end

        end

        % Value changed function: SmoothingmethodDropDown
        function SmoothingmethodDropDownValueChanged(app, event)
            value = app.SmoothingmethodDropDown.Value;
            app.SpatialdimensionsDropDown.Items = {'2D', '3D'};
            app.SpatialdimensionsDropDown.Value = '2D';
            app.xLabel.Visible = 'on';
            app.yLabel.Visible = 'on';
            app.FWHM_y.Visible = 'on';
            app.zLabel.Visible = 'off';
            app.FWHM_z.Visible = 'off';
            app.FilterwidthmmEditField.Visible = 'off';
            app.FilterwidthmmEditFieldLabel.Visible = 'off';

            if strcmp(value, 'Matlab Image Guided')
                app.SpatialdimensionsDropDown.Value = '2D';
                app.SigmarangeEditField.Visible = 'off';
                app.SigmarangeEditFieldLabel.Visible = 'off';
                app.SpatialdimensionsDropDown.Items = {'2D'};

            elseif strcmp(value, 'Bilateral')
                app.SigmarangeEditField.Visible = 'on';
                app.SigmarangeEditFieldLabel.Visible = 'on';

            elseif strcmp(value, 'TIPS')
                app.SigmarangeEditField.Visible = 'on';
                app.SigmarangeEditFieldLabel.Visible = 'on';

            elseif(strcmp(value, 'Gaussian'))
                app.SigmarangeEditField.Visible = 'off';
                app.SigmarangeEditFieldLabel.Visible = 'off';
                app.FilterwidthmmEditField.Visible = 'on';
                app.FilterwidthmmEditFieldLabel.Visible = 'on';

            elseif(strcmp(value, 'Standard Gaussian'))
                app.SigmarangeEditField.Visible = 'off';
                app.SigmarangeEditFieldLabel.Visible = 'off';
                app.FWHM_y.Visible = 'off';
                app.FWHM_z.Visible = 'off';
                app.yLabel.Visible = 'off';
                app.zLabel.Visible = 'off';
                app.xLabel.Visible = 'off';


            end
        end

        % Value changed function: SpatialdimensionsDropDown
        function SpatialdimensionsDropDownValueChanged(app, event)
            value = app.SpatialdimensionsDropDown.Value;

            if value == '3D'
                if(strcmp(app.SmoothingmethodDropDown.Value, 'Standard Gaussian'))
                    app.zLabel.Visible = 'off';
                    app.FWHM_z.Visible = 'off';
                else
                    app.zLabel.Visible = 'on';
                    app.FWHM_z.Visible = 'on';
                end
            else
                app.zLabel.Visible = 'off';
                app.FWHM_z.Visible = 'off';
            end
        end

        % Button pushed function: ApplysmoothingButton
        function ApplysmoothingButtonPushed(app, event)
            global opts

            app.ApplysmoothingButton.Enable = 'off';
            drawnow;

            %check if remainder is 0
            if rem(app.FilterwidthmmEditField.Value, 2) == 0
                app.StatussmoothingLabel.Text = 'Error: Filter width should be an odd integer.';
                app.StatussmoothingLabel.Visible = 'on';
                app.ApplysmoothingButton.Enable = 'on';
                return

            else
                try
                    if app.data.smooth == 1
                        if app.data.denoise == 1
                            app.SmoothPanelLabel.Text = {'Smoothing has already been performed.', ...
                                'If you wish to smooth data again,', 'then existing processes will be removed.', ...
                                'Denoising can be applied first.'}
                            app.Panel_smoothtwice.Visible = 'on';
                        else
                            app.SmoothPanelLabel.Text = {'Smoothing has already been performed.', ...
                                'If you wish to smooth data again,', 'then existing processes will be removed', ...
                                'and source data will be used.'}
                            app.Panel_smoothtwice.Visible = 'on';
                        end

                    else
                        apply_smoothing(app)

                    end

                catch
                    app.StatussmoothingLabel.Text = 'Status: An error has occurred.';
                    app.SmoothingactLabel.Text = 'Smoothing: no';
                    app.SmoothingactLabel.FontWeight = 'normal';
                    app.ApplysmoothingButton.Enable = 'on';
                    app.data.smooth = 0;
                    app.StatussmoothingLabel.Visible = 'off';
                    app.data.smoothData = [];
                    app.ApplysmoothingButton.Enable = 'on';
                    cla(app.UIAxes_smoothing);
                    app.UIAxes_smoothing.Visible = 'off';
                    app.SliceSlider_smoothing.Visible = 'off';
                    app.smoothsliceLabel.Visible = 'off';
                    app.SliceSliderLabel_2.Visible = 'off';
                    app.Panel_smoothtwice.Visible = 'off';
                    app.WhichmaskDropDown_2Label.Visible = 'off';
                    app.WhichsmoothmaskDropDown.Visible = 'off';

                end
            end

        end

        % Button pushed function: SmoothContinueButton
        function SmoothContinueButtonPushed(app, event)
            app.Panel_smoothtwice.Visible = 'off';
            drawnow;

            if app.data.denoise == 0
                removeallpreprocessing(app)
                app.ApplysmoothingButton.Enable = 'off';
                drawnow;
                apply_smoothing(app)
            else
                removeallpreprocessing(app)
            end

        end

        % Button pushed function: SmoothHoldButton
        function SmoothHoldButtonPushed(app, event)
            app.Panel_smoothtwice.Visible = 'off';
            drawnow;
            app.ApplysmoothingButton.Enable = 'on';

        end

        % Value changing function: SliceSlider_smoothing
        function SliceSlider_smoothingValueChanging(app, event)
            changingValue = event.Value;
            slice = round(changingValue);
            app.smoothsliceLabel.Text = append('Current slice: ', int2str(slice));

            cla(app.UIAxes_smoothing);
            imagesc(app.UIAxes_smoothing, squeeze(app.data.smthout(:,:,slice)));
        end

        % Button pushed function: RemoveallpreprocessingButton
        function RemoveallpreprocessingButtonPushed(app, event)
            removeallpreprocessing(app)
        end

        % Close request function: SeeVRUserInterfaceUIFigure
        function SeeVRUserInterfaceUIFigureCloseRequest(app, event)
            delete(app.CVRapp)
            delete(app.Plotapp)
            delete(app)

        end

        % Button pushed function: LoadRespirActdataButton
        function LoadRespirActdataButtonPushed(app, event)
            global opts

            try
                app.dirs.pathco2 = uigetdir;
                opts.seqpath = app.dirs.pathco2;
                [app.data.CO2_corr,app.data.O2_corr,app.data.Respiration_rate_corr] = loadRAMRgen4(opts);

                TS = meanTimeseries(app.data.prepro_sourceData,app.data.mask.GM);
                trAlign(app.data.CO2_corr, app.data.O2_corr, TS, opts);

                %get variables from base workspace
                probe1 = evalin('base', 'probe1');
                probe2 = evalin('base', 'probe2');
                app.data.CO2trace = probe1;
                app.data.O2trace = probe2;

                app.StatusRespirActLabel.Text = 'RespirAct data loaded in succesfully';
                app.lagCVR_co2_Button.Enable = 'on';
                app.Reclag_co2_Button.Enable = 'on';
                %                 app.TauDispersionButton.Enable = 'on';

            catch
                app.StatusRespirActLabel.Text = 'RespirAct data could not be loaded in succesfully.';

            end
        end

        % Button pushed function: lagCVR_noco2_Button
        function lagCVR_noco2_ButtonPushed(app, event)
            %For lagCVR, smoothing and denoising should have been done as
            %preprocessing steps
            if strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') || ...
                    strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')
                data_eval_str = 'app.data.prepro_sourceData';
            else
                data_eval_str = 'app.data.sourceData';
                app.StatusCVRLabel.Text = 'Data smoothing and de-noising is recommended for this analysis.'
            end

            if app.dp.LVfilter == 1
                mask_eval_str = 'app.data.mask.mmask';
            else
                mask_eval_str = 'app.data.mask.WB';
            end

            readmaskdropdown(app, app.ProbeDropDown);
            app.data.probe = meanTimeseries(app.data.prepro_sourceData, app.chosenmask);
            app.dirs.dirname_lagCVR = '\corrLAG';
            app.dirs.CVRdatapath_lagCVR = append(app.dirs.datapath, '\RESULTS');
            CO2_statement = 'Without CO2-data';

            app.CVRapp = app_lagCVR(app, logical(app.data.mask.GM).*logical(eval(mask_eval_str)), eval(mask_eval_str), ...
                imeseries(eval(data_eval_str),app.data.mask.WB, [1 20]), app.data.probe, [], ...
                app.dirs.CVRdatapath_lagCVR, CO2_statement, app.dp.res_nr_lagCVR);


            drawnow;

            app.StatusCVRLabel.Text = 'Fill in parameters in pop-up window.';
            app.data.currentCVRfunc = 'LagCVR without CO2-data';

            app.lagCVR_noco2_Button.Enable = 'on';

        end

        % Button pushed function: Reclag_noco2_Button
        function Reclag_noco2_ButtonPushed(app, event)
            %For recursiveLag, smoothing and denoising should have been done as
            %preprocessing steps
            if strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') || ...
                    strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')
                data_eval_str = 'app.data.prepro_sourceData';
            else
                data_eval_str = 'app.data.sourceData';
                app.StatusCVRLabel.Text = 'Data smoothing and de-noising is recommended for this analysis.'
            end

            if app.dp.LVfilter == 1
                mask_eval_str = 'app.data.mask.mmask';
            else
                mask_eval_str = 'app.data.mask.WB';
            end

            readmaskdropdown(app, app.ProbeDropDown);
            app.data.probe = meanTimeseries(app.data.prepro_sourceData, app.chosenmask);
            app.dirs.dirname_recLag = '\recLag_results';
            app.dirs.CVRdatapath_recLag = append(app.dirs.datapath, '\RESULTS');
            CO2_statement = 'Without CO2-data';


            app.CVRapp = app_recursiveLag(app, logical(app.data.mask.GM).*logical(eval(mask_eval_str)), eval(mask_eval_str), ...
                eval(data_eval_str), app.data.probe, [], app.dirs.CVRdatapath_recLag, ...
                CO2_statement, app.dp.res_nr_recLag)

            app.StatusCVRLabel.Text = 'Fill in parameters in pop-up window.';
            app.data.currentCVRfunc = 'Recursive lag without CO2 data';


        end

        % Button pushed function: RSBHCVRButton
        function RSBHCVRButtonPushed(app, event)

            if strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') || ...
                    strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')
                data_eval_str = 'app.data.prepro_sourceData';
            else
                data_eval_str = 'app.data.sourceData';
                app.StatusCVRLabel.Text = 'Data smoothing is recommended for this analysis.'
            end

            if app.dp.LVfilter == 1
                mask_eval_str = 'app.data.mask.mmask';
            else
                mask_eval_str = 'app.data.mask.WB';
            end

            readmaskdropdown(app, app.ProbeDropDown);
            app.data.probe = meanTimeseries(app.data.prepro_sourceData, app.chosenmask);
            app.dirs.dirname_GLM = '\CVRidx';
            app.dirs.CVRdatapath_GLM = append(app.dirs.datapath, '\RESULTS');

            %run app
            app.CVRapp = app_GLMCVRidx(app, eval(data_eval_str), eval(mask_eval_str),...
                logical(app.data.mask.GM).*logical(eval(mask_eval_str)), [], app.dirs.CVRdatapath_GLM, app.dp.res_nr_GLM);

            app.StatusCVRLabel.Text = 'Fill in parameters in pop-up window.';

            app.data.currentCVRfunc = 'GLMCVRidx';

        end

        % Button pushed function: lagCVR_co2_Button
        function lagCVR_co2_ButtonPushed(app, event)
            global opts

            if strcmp(app.chopDataDropDown.Value, 'Yes')
                [idx, ~] = chopTimeseries(app.data.sourceData, app.data.mask.WB);
                
            else
                idx = [1 size(app.data.sourceData,4)];
            end
            
            phys_trace = app.data.CO2trace(1,idx(1):idx(2));  %will add future option to choose O2
            
            if strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') || ...
                    strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')

                if strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')
                    data_eval_str = 'app.data.prepro_sourceData(:,:,:,idx(1):idx(2))';
                else
                    data_eval_str = 'normTimeseries(app.data.sourceData(:,:,:,idx(1):idx(2)), app.data.mask.WB, [1 20])';
                end

            else
                data_eval_str = 'app.data.sourceData(:,:,:,idx(1):idx(2))';
            end

            if app.dp.LVfilter == 1
                mask_eval_str = 'app.data.mask.mmask';
            else
                mask_eval_str = 'app.data.mask.WB';
            end

            app.dirs.dirname_lagCVR = '\corrLAG';
            app.dirs.CVRdatapath_lagCVR = append(app.dirs.datapath, '\RESULTS');
            CO2_statement = 'With CO2-data';

            %run app: select appropriate data and masks based on previous
            %processing steps (future implementation will allow drop-down
            %menu)

            app.CVRapp = app_lagCVR(app, logical(app.data.mask.GM).*logical(eval(mask_eval_str)), eval(mask_eval_str), ...
                eval(data_eval_str), phys_trace, [], ...
                app.dirs.CVRdatapath_lagCVR, CO2_statement, app.dp.res_nr_lagCVR);

            drawnow;

            app.StatusCVRLabel.Text = 'Fill in parameters in pop-up window.';
            app.data.currentCVRfunc = 'LagCVR with CO2-data';

            app.lagCVR_co2_Button.Enable = 'on';

        end

        % Button pushed function: Reclag_co2_Button
        function Reclag_co2_ButtonPushed(app, event)
            global opts

            if strcmp(app.chopDataDropDown.Value, 'yes')
                [idx, ~] = chopTimeseries(app.data.sourceData, app.data.mask.WB);
                phys_trace = app.data.CO2trace(1,idx(1):idx(2));  %will add future option to choose O2
            else
                idx = [0 size(app.data.sourceData,4)];
            end

            if strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') || ...
                    strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')

                if strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')
                    data_eval_str = 'app.data.prepro_sourceData(:,:,:,idx(1):idx(2))';
                else
                    data_eval_str = 'normTimeseries(app.data.sourceData(:,:,:,idx(1):idx(2)), app.data.mask.WB, [1 20])';
                end

            else
                data_eval_str = 'app.data.sourceData(:,:,:,idx(1):idx(2))';
            end

            if app.dp.LVfilter == 1
                mask_eval_str = 'app.data.mask.mmask';
            else
                mask_eval_str = 'app.data.mask.WB';
            end

            app.dirs.dirname_recLag = '\recLag_results';
            app.dirs.CVRdatapath_recLag = append(app.dirs.datapath, '\RESULTS');
            CO2_statement = 'With CO2-data';


            app.CVRapp = app_recursiveLag(app, logical(app.data.mask.GM).*logical(eval(mask_eval_str)), eval(mask_eval_str), ...
                eval(data_eval_str), phys_trace, [], ...
                app.dirs.CVRdatapath_recLag, CO2_statement, app.dp.res_nr_recLag);


            drawnow;

            app.StatusCVRLabel.Text = 'Fill in parameters in pop-up window.';
            app.data.currentCVRfunc = 'Recursive lag with CO2-data';

        end

        % Button pushed function: TauDispersionButton
        function TauDispersionButtonPushed(app, event)
            global opts

            if strcmp(app.chopDataDropDown.Value, 'yes')
                [idx, ~] = chopTimeseries(app.data.sourceData, app.data.mask.WB);
                phys_trace = app.data.PetCO2(1,idx(1):idx(2));  %will add future option to choose O2
            else
                idx = [0 size(app.data.sourceData,4)];
            end

            if strcmp(app.DenoisingactLabel.Text, 'Denoising: yes') || ...
                    strcmp(app.SmoothingactLabel.Text, 'Smoothing: yes')
                data_eval_str = 'app.data.prepro_sourceData(:,:,:,idx(1):idx(2))';
            else
                data_eval_str = 'app.data.sourceData(:,:,:,idx(1):idx(2))';
            end

            if app.dp.LVfilter == 1
                mask_eval_str = 'app.data.mask.mmask';
            else
                mask_eval_str = 'app.data.mask.WB';
            end

            % must be implemented

        end

        % Button pushed function: PlotlagCVR_no_Button
        function PlotlagCVR_no_ButtonPushed(app, event)

            app.Plotapp = plotresults(app, app.dirs.CVRdatapath_lagCVR_compl, ...
                app.dirs.seeVR, app.dirs.dirname_lagCVR, app.dirs.elastix, app.dirs.datapath)
        end

        % Button pushed function: PlotReclag_no_Button
        function PlotReclag_no_ButtonPushed(app, event)
            app.Plotapp = plotresults(app, app.dirs.CVRdatapath_recLag_compl, ...
                app.dirs.seeVR, app.dirs.dirname_recLag, app.dirs.elastix, app.dirs.datapath)
        end

        % Button pushed function: PlotRSBHCVRButton
        function PlotRSBHCVRButtonPushed(app, event)
            app.Plotapp = plotresults(app, app.dirs.CVRdatapath_GLM_compl, ...
                app.dirs.seeVR, app.dirs.dirname_GLM, app.dirs.elastix, app.dirs.datapath)
        end

        % Button pushed function: PlotlagCVR_co2_Button
        function PlotlagCVR_co2_ButtonPushed(app, event)
            app.Plotapp = plotresults(app, app.dirs.CVRdatapath_lagCVR_compl, ...
                app.dirs.seeVR, app.dirs.dirname_lagCVR, app.dirs.elastix, app.dirs.datapath)
        end

        % Button pushed function: PlotReclag_co2_Button
        function PlotReclag_co2_ButtonPushed(app, event)
            app.Plotapp = plotresults(app, app.dirs.CVRdatapath_recLag_compl, ...
                app.dirs.seeVR, app.dirs.dirname_recLag, app.dirs.elastix, app.dirs.datapath)
        end

        % Button pushed function: PlotTauDispersionButton
        function PlotTauDispersionButtonPushed(app, event)
            %             app.Plotapp = plotresults(app, ...)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeeVRUserInterfaceUIFigure = uifigure('Visible', 'off');
            app.SeeVRUserInterfaceUIFigure.Name = 'SeeVR User Interface';
            app.SeeVRUserInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @SeeVRUserInterfaceUIFigureCloseRequest, true);
            app.Image = uiimage(app.SeeVRUserInterfaceUIFigure);
            app.Image.ImageSource = 'seeVR.png';
            app.TabGroup = uitabgroup(app.SeeVRUserInterfaceUIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.UIAxes_source = uiaxes(app.InputTab);
            app.UIAxes_source.XTick = [];
            app.UIAxes_source.YTick = [];
            app.UIAxes_source.Visible = 'off';
            app.UIAxes_mask = uiaxes(app.InputTab);
            app.UIAxes_mask.XTick = [];
            app.UIAxes_mask.YTick = [];
            app.UIAxes_mask.Visible = 'off';
            app.UIAxes_tSNR = uiaxes(app.InputTab);
            app.UIAxes_tSNR.CLim = [0 100];
            app.UIAxes_tSNR.XTick = [];
            app.UIAxes_tSNR.YTick = [];
            app.UIAxes_tSNR.Visible = 'off';
            app.Label = uilabel(app.InputTab);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.volume_Slider = uislider(app.InputTab);
            app.volume_Slider.Limits = [1 100];
            app.volume_Slider.MajorTicks = [];
            app.volume_Slider.ValueChangedFcn = createCallbackFcn(app, @volume_SliderValueChanged, true);
            app.volume_Slider.MinorTicks = [];
            app.volume_Slider.Visible = 'off';
            app.volume_Slider.Value = 1;
            app.Label_2 = uilabel(app.InputTab);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.slice_Slider = uislider(app.InputTab);
            app.slice_Slider.Limits = [1 100];
            app.slice_Slider.MajorTicks = [];
            app.slice_Slider.ValueChangedFcn = createCallbackFcn(app, @slice_SliderValueChanged, true);
            app.slice_Slider.MinorTicks = [];
            app.slice_Slider.Visible = 'off';
            app.slice_Slider.Value = 1;
            app.remLV = uibutton(app.InputTab, 'push');
            app.remLV.ButtonPushedFcn = createCallbackFcn(app, @remLVButtonPushed, true);
            app.remLV.Visible = 'off';
            app.remLV.Tooltip = {'voxels are sorted in terms of their noise-to-signal values and thresholded based on the supplied percentile cut-off value'};
            app.remLV.Text = {'Remove large vessels'; '(modified mask)'};
            app.pctileEdit = uieditfield(app.InputTab, 'text');
            app.pctileEdit.Visible = 'off';
            app.pctileEdit.Value = '98';
            app.OrientationDropDownLabel = uilabel(app.InputTab);
            app.OrientationDropDownLabel.Visible = 'off';
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.InputTab);
            app.OrientationDropDown.Items = {'ORI1', 'ORI2', 'ORI3'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Visible = 'off';
            app.OrientationDropDown.Tooltip = {'select image orientation'};
            app.OrientationDropDown.Value = 'ORI1';
            app.slice_Slider_tSNR = uislider(app.InputTab);
            app.slice_Slider_tSNR.Limits = [1 100];
            app.slice_Slider_tSNR.MajorTicks = [];
            app.slice_Slider_tSNR.ValueChangedFcn = createCallbackFcn(app, @slice_Slider_tSNRValueChanged, true);
            app.slice_Slider_tSNR.MinorTicks = [];
            app.slice_Slider_tSNR.Visible = 'off';
            app.slice_Slider_tSNR.Value = 1;
            app.SlicesourceLabel = uilabel(app.InputTab);
            app.SlicesourceLabel.Visible = 'off';
            app.SlicesourceLabel.Text = 'Slice: ';
            app.VolumesourceLabel = uilabel(app.InputTab);
            app.VolumesourceLabel.Visible = 'off';
            app.VolumesourceLabel.Text = 'Volume: ';
            app.SlicetSNRLabel = uilabel(app.InputTab);
            app.SlicetSNRLabel.Visible = 'off';
            app.SlicetSNRLabel.Text = 'Slice: ';
            app.percentageLabel = uilabel(app.InputTab);
            app.percentageLabel.Visible = 'off';
            app.percentageLabel.Text = '%';
            app.DenoisingTab = uitab(app.TabGroup);
            app.DenoisingTab.Title = 'Denoising';
            app.UIAxes_noiseremoval = uiaxes(app.DenoisingTab);
            title(app.UIAxes_noiseremoval, 'Noise removal')
            xlabel(app.UIAxes_noiseremoval, 'time (s)')
            ylabel(app.UIAxes_noiseremoval, 'absolute signal')
            app.Panel_denoising = uipanel(app.DenoisingTab);
            app.DenoisingLabel = uilabel(app.Panel_denoising);
            app.DenoisingLabel.Text = 'Denoising';
            app.ApplydenButton = uibutton(app.Panel_denoising, 'push');
            app.ApplydenButton.ButtonPushedFcn = createCallbackFcn(app, @ApplydenoisingButtonPushed, true);
            app.ApplydenButton.Tooltip = {'Application of denoising will be done on current pre-processed data, or original data if no pre-processing steps are done.'};
            app.ApplydenButton.Text = 'Apply denoising';
            app.DenoisingmethodDropDownLabel = uilabel(app.Panel_denoising);
            app.DenoisingmethodDropDownLabel.Text = 'Denoising method';
            app.DenoisingmethodDropDown = uidropdown(app.Panel_denoising);
            app.DenoisingmethodDropDown.Items = {'Wavelet', 'Moving window', 'Bandpass filter'};
            app.DenoisingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DenoisingmethodDropDownValueChanged, true);
            app.DenoisingmethodDropDown.Value = 'Wavelet';
            app.FrequencybandpassEditFieldLabel = uilabel(app.Panel_denoising);
            app.FrequencybandpassEditFieldLabel.Visible = 'off';
            app.FrequencybandpassEditFieldLabel.Text = 'Frequency bandpass';
            app.FrequencybpminEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.FrequencybpminEditField.Limits = [1e-06 1];
            app.FrequencybpminEditField.Visible = 'off';
            app.FrequencybpminEditField.Value = 1e-06;
            app.minLabel = uilabel(app.Panel_denoising);
            app.minLabel.Visible = 'off';
            app.minLabel.Text = 'min.';
            app.maxLabel = uilabel(app.Panel_denoising);
            app.maxLabel.Visible = 'off';
            app.maxLabel.Text = 'max.';
            app.FrequencybpmaxEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.FrequencybpmaxEditField.Limits = [1e-06 1];
            app.FrequencybpmaxEditField.Visible = 'off';
            app.FrequencybpmaxEditField.Value = 0.1164;
            app.FilterorderEditFieldLabel = uilabel(app.Panel_denoising);
            app.FilterorderEditFieldLabel.Visible = 'off';
            app.FilterorderEditFieldLabel.Text = 'Filter order';
            app.FilterorderEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.FilterorderEditField.Visible = 'off';
            app.MethodDropDown_2Label = uilabel(app.Panel_denoising);
            app.MethodDropDown_2Label.Visible = 'off';
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodmovDropDown = uidropdown(app.Panel_denoising);
            app.MethodmovDropDown.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.MethodmovDropDown.Visible = 'off';
            app.MethodmovDropDown.Value = 'movmean';
            app.Smoothingfactor01EditFieldLabel = uilabel(app.Panel_denoising);
            app.Smoothingfactor01EditFieldLabel.Visible = 'off';
            app.Smoothingfactor01EditFieldLabel.Text = 'Smoothing factor (0-1)';
            app.Smoothingfactor01EditField = uieditfield(app.Panel_denoising, 'numeric');
            app.Smoothingfactor01EditField.Visible = 'off';
            app.Smoothingfactor01EditField.Value = 0.25;
            app.WindowsizeEditFieldLabel = uilabel(app.Panel_denoising);
            app.WindowsizeEditFieldLabel.Visible = 'off';
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.WindowsizeEditField.Visible = 'off';
            app.NoiseestLabel = uilabel(app.Panel_denoising);
            app.NoiseestLabel.Text = 'Noise estimation';
            app.ButtonGroup = uibuttongroup(app.Panel_denoising);
            app.LevelindepButton = uiradiobutton(app.ButtonGroup);
            app.LevelindepButton.Text = 'Level independent';
            app.LevelindepButton.Value = true;
            app.LeveldepButton = uiradiobutton(app.ButtonGroup);
            app.LeveldepButton.Text = 'Level dependent';
            app.ThresholdruleDropDownLabel = uilabel(app.Panel_denoising);
            app.ThresholdruleDropDownLabel.Text = 'Threshold rule';
            app.ThresruleDropDown = uidropdown(app.Panel_denoising);
            app.ThresruleDropDown.Items = {'Hard', 'Soft', 'Median', 'Mean'};
            app.ThresruleDropDown.Value = 'Hard';
            app.MethodDropDownLabel = uilabel(app.Panel_denoising);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodwavDropDown = uidropdown(app.Panel_denoising);
            app.MethodwavDropDown.Items = {'UniversalThreshold', 'Bayes', 'BlockJS', 'FDR', 'Minimax', 'SURE'};
            app.MethodwavDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodwavDropDownValueChanged, true);
            app.MethodwavDropDown.Value = 'UniversalThreshold';
            app.WaveletlevelEditFieldLabel = uilabel(app.Panel_denoising);
            app.WaveletlevelEditFieldLabel.Text = 'Wavelet level';
            app.WavlevelEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.WavlevelEditField.Limits = [1 9];
            app.WavlevelEditField.RoundFractionalValues = 'on';
            app.WavlevelEditField.Value = 2;
            app.WaveletfamilyDropDownLabel = uilabel(app.Panel_denoising);
            app.WaveletfamilyDropDownLabel.Text = 'Wavelet family';
            app.WavfamDropDown = uidropdown(app.Panel_denoising);
            app.WavfamDropDown.Items = {'db', 'sym', 'fk', 'bior', 'coif'};
            app.WavfamDropDown.ValueChangedFcn = createCallbackFcn(app, @WavfamDropDownValueChanged, true);
            app.WavfamDropDown.Value = 'db';
            app.FamilynumberDropDownLabel = uilabel(app.Panel_denoising);
            app.FamilynumberDropDownLabel.Text = 'Family number';
            app.FamilynumberDropDown = uidropdown(app.Panel_denoising);
            app.FamilynumberDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.FamilynumberDropDown.Value = '4';
            app.WhichmaskDropDownLabel = uilabel(app.DenoisingTab);
            app.WhichmaskDropDownLabel.Text = 'Which mask?';
            app.WhichmaskDropDown = uidropdown(app.DenoisingTab);
            app.WhichmaskDropDown.Items = {'Whole brain'};
            app.WhichmaskDropDown.ValueChangedFcn = createCallbackFcn(app, @WhichmaskDropDownValueChanged, true);
            app.WhichmaskDropDown.Value = 'Whole brain';
            app.LicensestatusLabel = uilabel(app.DenoisingTab);
            app.LicensestatusLabel.Visible = 'off';
            app.LicensestatusLabel.Text = 'License status';
            app.ErrorLabel = uilabel(app.DenoisingTab);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.Panel_denoisetwice = uipanel(app.DenoisingTab);
            app.Panel_denoisetwice.Visible = 'off';
            app.DenoisePanelLabel = uilabel(app.Panel_denoisetwice);
            app.DenoisePanelLabel.Text = {'Denoising has already been performed. '; 'If you wish to denoise data again, '; 'then existing processes will be removed '; 'and source data will be used.'};
            app.DenoiseContinueButton = uibutton(app.Panel_denoisetwice, 'push');
            app.DenoiseContinueButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseContinueButtonPushed, true);
            app.DenoiseContinueButton.Text = 'Yes';
            app.DenoiseHoldButton = uibutton(app.Panel_denoisetwice, 'push');
            app.DenoiseHoldButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseHoldButtonPushed, true);
            app.DenoiseHoldButton.Text = 'No';
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.UIAxes_smoothing = uiaxes(app.SmoothingTab);
            title(app.UIAxes_smoothing, {'Subtracted smoothing mask from denoised data'; ''})
            app.UIAxes_smoothing.XTick = [];
            app.UIAxes_smoothing.YTick = [];
            app.UIAxes_smoothing.Visible = 'off';
            app.Panel_smoothing = uipanel(app.SmoothingTab);
            app.SmoothingLabel = uilabel(app.Panel_smoothing);
            app.SmoothingLabel.Text = 'Smoothing';
            app.ApplysmoothingButton = uibutton(app.Panel_smoothing, 'push');
            app.ApplysmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysmoothingButtonPushed, true);
            app.ApplysmoothingButton.Tooltip = {'Application of smoothing will be done on current pre-processed data, or original data if no pre-processing steps are done.'};
            app.ApplysmoothingButton.Text = 'Apply smoothing';
            app.SmoothingmethodDropDownLabel = uilabel(app.Panel_smoothing);
            app.SmoothingmethodDropDownLabel.Text = 'Smoothing method';
            app.SmoothingmethodDropDown = uidropdown(app.Panel_smoothing);
            app.SmoothingmethodDropDown.Items = {'Matlab Image Guided', 'Bilateral', 'TIPS', 'Standard Gaussian', 'Gaussian'};
            app.SmoothingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingmethodDropDownValueChanged, true);
            app.SmoothingmethodDropDown.Value = 'Gaussian';
            app.FilterwidthmmEditFieldLabel = uilabel(app.Panel_smoothing);
            app.FilterwidthmmEditFieldLabel.Visible = 'off';
            app.FilterwidthmmEditFieldLabel.Text = 'Filter width (mm)';
            app.FilterwidthmmEditField = uieditfield(app.Panel_smoothing, 'numeric');
            app.FilterwidthmmEditField.Visible = 'off';
            app.FilterwidthmmEditField.Value = 5;
            app.SigmarangeEditFieldLabel = uilabel(app.Panel_smoothing);
            app.SigmarangeEditFieldLabel.Visible = 'off';
            app.SigmarangeEditFieldLabel.Text = 'Sigma range';
            app.SigmarangeEditField = uieditfield(app.Panel_smoothing, 'numeric');
            app.SigmarangeEditField.Visible = 'off';
            app.xLabel = uilabel(app.Panel_smoothing);
            app.xLabel.Text = 'x';
            app.FWHM_y = uieditfield(app.Panel_smoothing, 'numeric');
            app.FWHM_y.Limits = [0 Inf];
            app.FWHM_y.Value = 4;
            app.yLabel = uilabel(app.Panel_smoothing);
            app.yLabel.Text = 'y';
            app.FWHM_z = uieditfield(app.Panel_smoothing, 'numeric');
            app.FWHM_z.Limits = [0 Inf];
            app.FWHM_z.Visible = 'off';
            app.FWHM_z.Value = 4;
            app.zLabel = uilabel(app.Panel_smoothing);
            app.zLabel.Visible = 'off';
            app.zLabel.Text = 'z';
            app.FWHMofkernelmmEditFieldLabel = uilabel(app.Panel_smoothing);
            app.FWHMofkernelmmEditFieldLabel.Text = 'FWHM of kernel (mm)';
            app.FWHM_x = uieditfield(app.Panel_smoothing, 'numeric');
            app.FWHM_x.Limits = [0 Inf];
            app.FWHM_x.Value = 4;
            app.SpatialdimensionsDropDownLabel = uilabel(app.Panel_smoothing);
            app.SpatialdimensionsDropDownLabel.Text = 'Spatial dimensions';
            app.SpatialdimensionsDropDown = uidropdown(app.Panel_smoothing);
            app.SpatialdimensionsDropDown.Items = {'2D', '3D'};
            app.SpatialdimensionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpatialdimensionsDropDownValueChanged, true);
            app.SpatialdimensionsDropDown.Value = '2D';
            app.WhichmaskDropDown_2Label = uilabel(app.Panel_smoothing);
            app.WhichmaskDropDown_2Label.Text = 'Which mask?';
            app.WhichsmoothmaskDropDown = uidropdown(app.Panel_smoothing);
            app.WhichsmoothmaskDropDown.Items = {'Modified mask', 'Original mask'};
            app.WhichsmoothmaskDropDown.Value = 'Modified mask';
            app.StatussmoothingLabel = uilabel(app.SmoothingTab);
            app.StatussmoothingLabel.Visible = 'off';
            app.StatussmoothingLabel.Text = {'Status:'; ''};
            app.SliceSliderLabel_2 = uilabel(app.SmoothingTab);
            app.SliceSliderLabel_2.Visible = 'off';
            app.SliceSliderLabel_2.Text = 'Slice';
            app.SliceSlider_smoothing = uislider(app.SmoothingTab);
            app.SliceSlider_smoothing.MajorTicks = [];
            app.SliceSlider_smoothing.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_smoothingValueChanging, true);
            app.SliceSlider_smoothing.MinorTicks = [];
            app.SliceSlider_smoothing.Visible = 'off';
            app.smoothsliceLabel = uilabel(app.SmoothingTab);
            app.smoothsliceLabel.Visible = 'off';
            app.smoothsliceLabel.Text = 'Current slice: ';
            app.Panel_smoothtwice = uipanel(app.SmoothingTab);
            app.Panel_smoothtwice.Visible = 'off';
            app.SmoothPanelLabel = uilabel(app.Panel_smoothtwice);
            app.SmoothPanelLabel.Text = {'Smoothing has already been performed. '; 'If you wish to smooth data again, '; 'then existing processes will be removed '; 'and source data will be used.'};
            app.SmoothContinueButton = uibutton(app.Panel_smoothtwice, 'push');
            app.SmoothContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothContinueButtonPushed, true);
            app.SmoothContinueButton.Text = 'Yes';
            app.SmoothHoldButton = uibutton(app.Panel_smoothtwice, 'push');
            app.SmoothHoldButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothHoldButtonPushed, true);
            app.SmoothHoldButton.Text = 'No';
            app.DataProcessingTab = uitab(app.TabGroup);
            app.DataProcessingTab.Title = 'Data Processing';
            app.WithtracesLabel = uilabel(app.DataProcessingTab);
            app.WithtracesLabel.Text = 'With traces:';
            app.TauDispersionButton = uibutton(app.DataProcessingTab, 'push');
            app.TauDispersionButton.ButtonPushedFcn = createCallbackFcn(app, @TauDispersionButtonPushed, true);
            app.TauDispersionButton.Enable = 'off';
            app.TauDispersionButton.Tooltip = {'Still needs explanation'; ''};
            app.TauDispersionButton.Text = 'Tau (Dispersion)';
            app.lagCVR_co2_Button = uibutton(app.DataProcessingTab, 'push');
            app.lagCVR_co2_Button.ButtonPushedFcn = createCallbackFcn(app, @lagCVR_co2_ButtonPushed, true);
            app.lagCVR_co2_Button.Enable = 'off';
            app.lagCVR_co2_Button.Tooltip = {'Requires denoising and smoothing preprocessing steps. '; 'Generates hemodynamic lag maps and CVR maps along with statistical maps based on 3D (x,y,t) '' ...'; '                ''or 4D (x,y,z,t) input data. Lag maps can be calculated using a correlation approach or a GLM based approach '' ...'; '                ''using shifted regressors. Maps can be generated based on]the original input probe (i.e. end-tidal CO2 or ROI signal) '' ...'; '                ''or based on an optimized BOLD regressor can be generated using the lagCVR function.'};
            app.lagCVR_co2_Button.Text = 'Reactivity & Lag';
            app.Reclag_co2_Button = uibutton(app.DataProcessingTab, 'push');
            app.Reclag_co2_Button.ButtonPushedFcn = createCallbackFcn(app, @Reclag_co2_ButtonPushed, true);
            app.Reclag_co2_Button.Enable = 'off';
            app.Reclag_co2_Button.Tooltip = {'Requires denoising and smoothing preprocessing steps. '; 'Recursive estimation of perfusion lag structure.'};
            app.Reclag_co2_Button.Text = 'Recursive Lag';
            app.WithouttracesLabel = uilabel(app.DataProcessingTab);
            app.WithouttracesLabel.Text = 'Without traces:';
            app.lagCVR_noco2_Button = uibutton(app.DataProcessingTab, 'push');
            app.lagCVR_noco2_Button.ButtonPushedFcn = createCallbackFcn(app, @lagCVR_noco2_ButtonPushed, true);
            app.lagCVR_noco2_Button.Tooltip = {'Requires denoising and smoothing preprocessing steps. '; 'Generates hemodynamic lag maps and CVR maps along with statistical maps based on 3D (x,y,t) '' ...'; '                ''or 4D (x,y,z,t) input data. Lag maps can be calculated using a correlation approach or a GLM based approach '' ...'; '                ''using shifted regressors. Maps can be generated based on]the original input probe (i.e. end-tidal CO2 or ROI signal) '' ...'; '                ''or based on an optimized BOLD regressor can be generated using the lagCVR function.'};
            app.lagCVR_noco2_Button.Text = 'Reactivity & Lag';
            app.Reclag_noco2_Button = uibutton(app.DataProcessingTab, 'push');
            app.Reclag_noco2_Button.ButtonPushedFcn = createCallbackFcn(app, @Reclag_noco2_ButtonPushed, true);
            app.Reclag_noco2_Button.Tooltip = {'Requires denoising and smoothing preprocessing steps. '; 'Recursive estimation of perfusion lag structure.'};
            app.Reclag_noco2_Button.Text = 'Recursive lag';
            app.RSBHCVRButton = uibutton(app.DataProcessingTab, 'push');
            app.RSBHCVRButton.ButtonPushedFcn = createCallbackFcn(app, @RSBHCVRButtonPushed, true);
            app.RSBHCVRButton.Tooltip = {'Requires smoothing. '; 'This function uses a reference signal (i.e. from a GM or cerebelum mask) as a explanatory variable and outputs a parameter map of beta values that can be interpreted as the relative CVR response.'};
            app.RSBHCVRButton.Text = 'RS/BH CVR';
            app.Label_4 = uilabel(app.DataProcessingTab);
            app.Label_4.Text = {'Select processing method. In the absence of physiological traces, internal reference signals are used as probes.   '; 'A new window will pop-up for parameter selection and analysis.'};
            app.DataProcessingLabel = uilabel(app.DataProcessingTab);
            app.DataProcessingLabel.Text = 'Data Processing';
            app.LoadRespirActdataButton = uibutton(app.DataProcessingTab, 'push');
            app.LoadRespirActdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRespirActdataButtonPushed, true);
            app.LoadRespirActdataButton.Text = 'Load RespirAct data';
            app.LoadtxtfileButton = uibutton(app.DataProcessingTab, 'push');
            app.LoadtxtfileButton.Enable = 'off';
            app.LoadtxtfileButton.Text = 'Load .txt file';
            app.StatusRespirActLabel = uilabel(app.DataProcessingTab);
            app.StatusRespirActLabel.Text = 'No physiological data has been loaded';
            app.ProbeDropDownLabel = uilabel(app.DataProcessingTab);
            app.ProbeDropDownLabel.Text = 'Probe';
            app.ProbeDropDown = uidropdown(app.DataProcessingTab);
            app.ProbeDropDown.Items = {'Whole brain', ''};
            app.ProbeDropDown.Value = 'Whole brain';
            app.StatusCVRLabel = uilabel(app.DataProcessingTab);
            app.StatusCVRLabel.Text = 'Status: No calculation has been done';
            app.WouldyouliketoprocessonlyaportionofthetimeseriesLabel = uilabel(app.DataProcessingTab);
            app.WouldyouliketoprocessonlyaportionofthetimeseriesLabel.Text = 'Would you like to process only a portion of the time series?';
            app.chopDataDropDown = uidropdown(app.DataProcessingTab);
            app.chopDataDropDown.Items = {'No', 'Yes'};
            app.chopDataDropDown.Value = 'No';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.Label_5 = uilabel(app.PlottingTab);
            app.Label_5.Text = 'Plot options will become available when associated data processing has been performed (Data Processing tab)';
            app.DataPlottingLabel = uilabel(app.PlottingTab);
            app.DataPlottingLabel.Text = 'Data Plotting';
            app.PlotTauDispersionButton = uibutton(app.PlottingTab, 'push');
            app.PlotTauDispersionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTauDispersionButtonPushed, true);
            app.PlotTauDispersionButton.Enable = 'off';
            app.PlotTauDispersionButton.Tooltip = {'Still needs explanation'; ''};
            app.PlotTauDispersionButton.Text = 'Plot Tau (Dispersion)';
            app.PlotlagCVR_co2_Button = uibutton(app.PlottingTab, 'push');
            app.PlotlagCVR_co2_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotlagCVR_co2_ButtonPushed, true);
            app.PlotlagCVR_co2_Button.Enable = 'off';
            app.PlotlagCVR_co2_Button.Tooltip = {'Generates hemodynamic lag maps and CVR maps along with statistical maps based on 3D (x,y,t) '' ...'; '                ''or 4D (x,y,z,t) input data. Lag maps can be calculated using a correlation approach or a GLM based approach '' ...'; '                ''using shifted regressors. Maps can be generated based on]the original input probe (i.e. end-tidal CO2 or ROI signal) '' ...'; '                ''or based on an optimized BOLD regressor can be generated using the lagCVR function.'};
            app.PlotlagCVR_co2_Button.Text = 'Plot Reactivity & lag';
            app.PlotReclag_co2_Button = uibutton(app.PlottingTab, 'push');
            app.PlotReclag_co2_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotReclag_co2_ButtonPushed, true);
            app.PlotReclag_co2_Button.Enable = 'off';
            app.PlotReclag_co2_Button.Tooltip = {'Recursive estimation of perfusion lag structure.'};
            app.PlotReclag_co2_Button.Text = 'Plot Recursive lag';
            app.PlotlagCVR_no_Button = uibutton(app.PlottingTab, 'push');
            app.PlotlagCVR_no_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotlagCVR_no_ButtonPushed, true);
            app.PlotlagCVR_no_Button.Enable = 'off';
            app.PlotlagCVR_no_Button.Tooltip = {'Generates hemodynamic lag maps and CVR maps along with statistical maps based on 3D (x,y,t) '' ...'; '                ''or 4D (x,y,z,t) input data. Lag maps can be calculated using a correlation approach or a GLM based approach '' ...'; '                ''using shifted regressors. Maps can be generated based on]the original input probe (i.e. end-tidal CO2 or ROI signal) '' ...'; '                ''or based on an optimized BOLD regressor can be generated using the lagCVR function.'};
            app.PlotlagCVR_no_Button.Text = 'Plot Reactivity & Lag';
            app.PlotReclag_no_Button = uibutton(app.PlottingTab, 'push');
            app.PlotReclag_no_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotReclag_no_ButtonPushed, true);
            app.PlotReclag_no_Button.Enable = 'off';
            app.PlotReclag_no_Button.Tooltip = {'Recursive estimation of perfusion lag structure.'};
            app.PlotReclag_no_Button.Text = 'Plot Recursive lag';
            app.PlotRSBHCVRButton = uibutton(app.PlottingTab, 'push');
            app.PlotRSBHCVRButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRSBHCVRButtonPushed, true);
            app.PlotRSBHCVRButton.Enable = 'off';
            app.PlotRSBHCVRButton.Tooltip = {'STILL NEEDS EXPLANATION'};
            app.PlotRSBHCVRButton.Text = 'Plot RS/BH CVR';
            app.WithtracesLabel_2 = uilabel(app.PlottingTab);
            app.WithtracesLabel_2.Text = 'With traces:';
            app.WithouttracesLabel_2 = uilabel(app.PlottingTab);
            app.WithouttracesLabel_2.Text = 'Without traces:';
            app.SelectinputdirectoryButton = uibutton(app.SeeVRUserInterfaceUIFigure, 'push');
            app.SelectinputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputdirectoryButtonPushed, true);
            app.SelectinputdirectoryButton.Text = 'Select input directory';
            app.RegisterfunctoanatButton = uibutton(app.SeeVRUserInterfaceUIFigure, 'push');
            app.RegisterfunctoanatButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterfunctoanatButtonPushed, true);
            app.RegisterfunctoanatButton.Visible = 'off';
            app.RegisterfunctoanatButton.Text = 'Register func to anat';
            app.DenoisingactLabel = uilabel(app.SeeVRUserInterfaceUIFigure);
            app.DenoisingactLabel.Visible = 'off';
            app.DenoisingactLabel.Text = 'Denoising: no';
            app.SmoothingactLabel = uilabel(app.SeeVRUserInterfaceUIFigure);
            app.SmoothingactLabel.Visible = 'off';
            app.SmoothingactLabel.Text = 'Smoothing: no';
            app.RemoveallpreprocessingButton = uibutton(app.SeeVRUserInterfaceUIFigure, 'push');
            app.RemoveallpreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveallpreprocessingButtonPushed, true);
            app.RemoveallpreprocessingButton.Visible = 'off';
            app.RemoveallpreprocessingButton.Text = 'Remove all pre-processing';
            app.ModifiedmaskLabel = uilabel(app.SeeVRUserInterfaceUIFigure);
            app.ModifiedmaskLabel.Visible = 'off';
            app.ModifiedmaskLabel.Text = 'Modified mask: no';
            % Show the figure after all components are created
            app.SeeVRUserInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seeVR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeeVRUserInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeeVRUserInterfaceUIFigure)
        end
    end
end